classdef ADCS_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ImportResultsButton          matlab.ui.control.Button
        SaveResultsButton            matlab.ui.control.Button
        PropagateOrbitButton         matlab.ui.control.Button
        ActuatorsPanel               matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        EnablemagnetorquersCheckBox  matlab.ui.control.CheckBox
        BrushlessMotorsButton        matlab.ui.control.Button
        RWConfigurationButton        matlab.ui.control.Button
        AttitudeDeterminationPanel   matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        AutoTuningButton             matlab.ui.control.Button
        IMUsophCheckBox              matlab.ui.control.CheckBox
        ADS_DropDown                 matlab.ui.control.DropDown
        DisableAHRSCheckBox          matlab.ui.control.CheckBox
        Ekf_parameters_button        matlab.ui.control.Button
        sensors_button               matlab.ui.control.Button
        SimulationSettingsPanel      matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        InitialTimeLabel_5           matlab.ui.control.Label
        InitialTimeLabel_4           matlab.ui.control.Label
        step_edit                    matlab.ui.control.NumericEditField
        InitialTimeLabel_3           matlab.ui.control.Label
        finalTime_edit               matlab.ui.control.NumericEditField
        initTime_edit                matlab.ui.control.NumericEditField
        text17_3                     matlab.ui.control.Label
        text17_2                     matlab.ui.control.Label
        text17                       matlab.ui.control.Label
        AttitudeControlPanel         matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        DisableAHRSfeedbackCheckBox  matlab.ui.control.CheckBox
        AutotuningButton             matlab.ui.control.Button
        controller_button            matlab.ui.control.Button
        controller_popupmenu         matlab.ui.control.DropDown
        ModelSettingsPanel           matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        MoreSettingsButton           matlab.ui.control.Button
        cubesat_button               matlab.ui.control.Button
        perturbation_button          matlab.ui.control.Button
        run_button                   matlab.ui.control.Button
        uibuttongroup7               matlab.ui.container.ButtonGroup
        GridLayout6                  matlab.ui.container.GridLayout
        compCost_text                matlab.ui.control.Label
        ascct_text                   matlab.ui.control.Label
        eulerint_text                matlab.ui.control.Label
        settime_text                 matlab.ui.control.Label
        text11                       matlab.ui.control.Label
        text10                       matlab.ui.control.Label
        text9                        matlab.ui.control.Label
        text8                        matlab.ui.control.Label
        uibuttongroup1               matlab.ui.container.ButtonGroup
        GridLayout4                  matlab.ui.container.GridLayout
        CubeSatAnimationLabel        matlab.ui.control.Label
        time_sLabel                  matlab.ui.control.Label
        TLabel                       matlab.ui.control.Label
        graphic2_run_button          matlab.ui.control.Button
        graphic2_stop_button         matlab.ui.control.Button
        graphic2_axes                matlab.ui.control.UIAxes
        uipanel1                     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        RMSE_Label                   matlab.ui.control.Label
        text2                        matlab.ui.control.Label
        graphic1_popupmenu           matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        text16                       matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu2                        matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        callSatParameters           % Handle to CubeSat settings window
        callDisturbances            % Handle to disturbance settings window
        rotBody                     % Graphics handles for body frame animation
        isDataOutdated = true;      % Flag to check if a simulation is necessary
    end
    
    properties (Access = public)
       simulationParameters         % Simulation Parameters
       simulationResults            % Struct with simulation results and time
    end
    
    methods (Access = private)
        function mask = updateMaskParameters(~, parameters)
            % UPDATEMASKPARAMETERS Genera y actualiza la estructura de la máscara de tiempo.
            %
            %   Esta función extrae los parámetros de tiempo de la simulación principal
            %   y de los datos de perturbación para crear una estructura 'mask' consolidada. 
            %   Esta máscara define la ventana temporal activa y se utiliza para el 
            %   remuestreo (resampling) o interpolación de los torques ambientales 
            %   (ej. datos orbitales) para que coincidan con el paso de integración del ADCS.
            %
            %   Sintaxis:
            %       mask = updateMaskParameters(~, parameters)
            %
            %   Entradas:
            %       ~          - Handle de la aplicación (App Designer). No se usa en esta función.
            %       parameters - Estructura global que contiene la configuración del simulador.
            %                    Debe incluir:
            %                    * parameters.sim.t0   (Tiempo inicial de simulación)
            %                    * parameters.sim.tf   (Tiempo final de simulación)
            %                    * parameters.sim.step (Paso de tiempo de integración)
            %                    * parameters.disturbances.mask.t (Vector de tiempo de los datos de perturbación)
            %
            %   Salidas:
            %       mask - Estructura con los límites temporales actualizados:
            %              * .t           : [1xN double] Vector de tiempo para la simulación principal.
            %              * .Ts          : [double] Periodo de muestreo de los datos de perturbación originales.
            %              * .nSteps      : [double] Número de muestras en los datos de perturbación originales.
            %              * .widhtWindow : [double] Duración total de la ventana de simulación (tf - t0).
            %              * .t_ini       : [double] Marca de tiempo inicial de la ventana.
            %              * .t_end       : [double] Marca de tiempo final de la ventana.
            
            mask.t = parameters.sim.t0:parameters.sim.step:parameters.sim.tf;
            mask.Ts = parameters.disturbances.mask.t(2)-parameters.disturbances.mask.t(1);
            mask.nSteps = numel(parameters.disturbances.mask.t);
            mask.widhtWindow = parameters.sim.tf - parameters.sim.t0;
            mask.t_ini = parameters.sim.t0;
            mask.t_end = parameters.sim.tf;
        end

        function [timeResults, timeSettings]  = getCurrentTimeSettings(app)
        %GETCURRENTTIMESETTINGS Reads and validates time parameters from the UI.
        %   Returns a struct containing:
        %       t0      - initial time [s]
        %       tf      - final time [s]
        %       step    - simulation step size [s]
        %       t       - time vector
        %       nSteps  - number of simulation steps
        
            timeSettings.t0   = app.initTime_edit.Value;
            timeSettings.tf   = app.finalTime_edit.Value;
            timeSettings.step = app.step_edit.Value;
            
            timeResults = app.validateData(timeSettings);
        end

        function time_results = validateData(app, time)
        % VALIDATEDATA Validates simulation time parameters and generates the time vector.
        %
        %   This function ensures that the time span is logical and that the integration
        %   step won't lead to memory overflows or infinite loops.
        %
        %   INPUTS:
        %       app  : Handle to the main application (for uialert context).
        %       time : Struct containing .t0 (start), .tf (end), and .step (dt).
        %
        %   OUTPUTS:
        %       time_results.t      : Row vector of time points. Returns [] on error.
        %       time_results.nSteps : Total number of simulation points. Returns 0 on error.
        
            % Default outputs for failure cases
            time_results.t = [];
            time_results.nSteps = 0;
        
            try
                % 1. Logical and Numeric Validation
                if any(isnan([time.t0, time.tf, time.step]))
                    error('All time parameters must be valid numeric values.');
                end
                
                if time.t0 >= time.tf
                    error('Initial Time (%g) must be strictly lower than Final Time (%g).', time.t0, time.tf);
                end
                
                if time.step <= 0
                    error('Step size must be a positive value.');
                end
                
                if time.step > (time.tf - time.t0)
                    error('Step size exceeds the total simulation duration.');
                end
        
                % 2. Vector Generation
                time_results.t = time.t0 : time.step : time.tf;
                time_results.nSteps = numel(time_results.t);
        
                % 3. Memory Safeguard
                % Large vectors can freeze the UI during plot rendering.
                if time_results.nSteps > 1e6
                    warning('High resolution: %d steps may impact performance.', time_results.nSteps);
                end
        
            catch ME
                % Using uialert instead of errordlg for better integration with UIFigure
                uialert(app.UIFigure, ME.message, 'Validation Error', ...
                        'Icon', 'error', 'Modal', true);
            end
        end

        function animation(app, x_state, hx, lx, hy, ly, hz, lz)
        % ANIMATION Renders the 3D attitude motion of the satellite.
        %
        %   This function animates the satellite's orientation using a state vector 
        %   of quaternions. It includes a frame-rate control (decimation) to ensure 
        %   smooth playback and a stop-button listener for user interruption.
        %
        %   INPUTS:
        %       app     - Handle to the main application object.
        %       x_state - (NxM) State matrix where rows 1:4 are quaternions [q0; q1; q2; q3].
        %       hx, lx, hy, ly, hz, lz - Graphics handles for the triad axes and labels.
        
            %%% Import ADCSIM utilities for quaternion and frame management
            import adcsim.utils.*
        
            % --- Interruption Logic ---
            % Initialize a stop flag in the app's root memory to listen for the stop button.
            setappdata(0, 'StopLoop', false);
            app.graphic2_stop_button.ButtonPushedFcn = @(src,event) setappdata(0, 'StopLoop', true);
            
            % --- Animation Timing Configuration ---
            total_steps = size(x_state, 2);
            num_frames = 300; % Targeted number of frames to display for the full duration.
            
            % Calculate the downsampling step (decimation) to maintain performance.
            paso = max(1, round(total_steps / num_frames));
            
            % --- Animation Loop ---
            for i = 1:paso:total_steps
                % 1. Extract and normalize the current quaternion
                q_current = x_state(1:4, i);
                
                % 2. Convert quaternion to a Direction Cosine Matrix (DCM)
                % This represents the rotation from the reference frame to the Body frame.
                B_current = quat2rot(q_current);
                
                % 3. Update the 3D Graphical Triad
                % The origin is fixed at [0,0,0] to visualize pure attitude change.
                app.updateFrame([0,0,0]', B_current, 1, hx, lx, hy, ly, hz, lz);
                
                % 4. Update the Time UI Label
                % Displays simulation time formatted to 3 decimal places.
                app.time_sLabel.Text = [num2str(app.simulationResults.adcSim.t(i),'%.3f'),' s'];
                
                % 5. Refresh Graphics
                % 'limitrate' skips frames if the renderer cannot maintain the speed.
                drawnow limitrate; 
                
                % 6. Check for User Interruption
                if getappdata(0, 'StopLoop')
                    break;
                end
            end
            
            % --- UI State Cleanup ---
            % Restore button states once animation finishes or is stopped.
            app.graphic2_stop_button.Enable = "off";
            app.graphic2_run_button.Enable  = "on";
        end
        
        function universalPlot(app, time, data, labels, ylabelStr, showSettling)
            % UNIVERSALPLOT Generic plotting engine for ADCSim.
            %
            %   INPUTS:
            %       app       - Handle to the main application.
            %       time      - Time vector (X-axis).
            %       data      - Matrix where each row is a signal to plot.
            %       labels    - Cell array of strings for the legend.
            %       ylabelStr - String for the Y-axis label.
            %       showSettling - Boolean. If true, draws vertical settling time lines.
        
            % 1. Prepare Axes
            cla(app.UIAxes, 'reset');
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
        
            % 2. Dynamic Plotting
            numSignals = size(data, 1);
            plotHandles = gobjects(1, numSignals);
            lineHandles = gobjects(1, numSignals); % For settling time lines
        
            for i = 1:numSignals
                % Plot the main data signal
                plotHandles(i) = plot(app.UIAxes, time, data(i, :), 'LineWidth', 1.5);
            end

            % 3. Optional: Settling Time Lines
            % Only if requested and if the index exists in the results
            if showSettling && isfield(app.simulationResults.adcSim.index, 'ts')
                for i = 1:numSignals
                    ts_val = app.simulationResults.adcSim.index.ts(i);
                    if isfinite(ts_val)
                        lineHandles(i) = line(app.UIAxes, [ts_val, ts_val], app.UIAxes.YLim, ...
                            'LineStyle', '--', 'Color', plotHandles(i).Color, 'LineWidth', 1);
                    end
                end
            end
        
            % 4. Flexible Legend Construction
            % Combine labels for signals and optionally for settling times
            allLabels = labels;
            if showSettling
                for i = 1:numSignals
                    allLabels{end+1} = ['ts_{', labels{i}, '}'];
                end
            end
        
            % Filter only valid graphics objects (handles that were actually created)
            totalHandles = [plotHandles, lineHandles];
            validIdx = isgraphics(totalHandles);
            legend(app.UIAxes, totalHandles(validIdx), allLabels(validIdx), 'Location', 'best');
        
            % 5. Final Formatting
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, ylabelStr);
            hold(app.UIAxes, 'off');
        end

        function refreshUIFromParameters(app)
            % REFRESHUIFROMPARAMETERS Synchronizes all UI components with the current
            % simulationParameters structure. Essential for consistency after data import.
        
            p = app.simulationParameters;
        
            % --- Simulation Settings ---
            app.initTime_edit.Value  = p.sim.t0;
            app.finalTime_edit.Value = p.sim.tf;
            app.step_edit.Value      = p.sim.step;
        
            % --- Controller & AHRS Settings ---
            app.controller_popupmenu.ValueIndex = p.controller.selector;
            app.ADS_DropDown.Value              = p.ahrs.flag;
            app.DisableAHRSCheckBox.Value       = ~p.ahrs.enable;
            app.DisableAHRSfeedbackCheckBox.Value = ~p.ahrs.enable_feedback;
        
            % --- Sensor Settings ---
            app.IMUsophCheckBox.Value = ~p.sensors_selector.imu.flag;
        
            % --- 3D Visualization Update ---
            % Reset the 3D body frame orientation to match the loaded initial state
            import adcsim.utils.*
            B_init = quat2rot(p.initialValues.q0);
            app.updateFrame([0,0,0]', B_init, 1, app.rotBody.hx, app.rotBody.lx, ...
                            app.rotBody.hy, app.rotBody.ly, ...
                            app.rotBody.hz, app.rotBody.lz);
                        
            drawnow; % Force UI refresh
        end

        function [t_dense, torque_dense] = get_localized_resampling(~, orekit_t, orekit_torque, t_ini, t_end, Ts)
            % GET_LOCALIZED_RESAMPLING extracts and interpolates orbital data for a 
            % specific time window. It is generalized to work perfectly whether the 
            % mask covers zero points (sub-step), one point, or multiple points.
            %
            % Inputs:
            %   orekit_t      - [1xN] Sparse time vector from Orekit
            %   orekit_torque - [3xN] Sparse disturbance torque matrix
            %   t_ini         - [double] Start time of the mask
            %   t_end         - [double] End time of the mask
            %   Ts            - [double] Target sampling time for adcSim (e.g., 1e-4)
            %
            % Outputs:
            %   t_dense       - High-resolution time vector strictly within the mask
            %   torque_dense  - Interpolated torque matrix at high frequency
        
            % 1. Find the strict mathematical boundaries
            % Find the index of the closest Orekit point BEFORE or exactly at t_ini
            idx_start = find(orekit_t <= t_ini, 1, 'last');
            
            % Find the index of the closest Orekit point AFTER or exactly at t_end
            idx_end   = find(orekit_t >= t_end, 1, 'first');
            
            % Handle out-of-bounds edge cases (if mask is beyond available data)
            if isempty(idx_start), idx_start = 1; end
            if isempty(idx_end),   idx_end = length(orekit_t); end
            
            % 2. Apply a Safety Margin for generalized cubic interpolation
            % We expand the indices by 2 points on each side. This guarantees that 
            % 'pchip' has enough boundary context to calculate slopes correctly, 
            % regardless of how many points are inside the actual mask.
            margin_points = 2;
            idx_start = max(1, idx_start - margin_points);
            idx_end   = min(length(orekit_t), idx_end + margin_points);
            
            % 3. Extract the localized dataset
            % If the mask covers 50 points, this slice will grab all 50 + margins.
            % If the mask covers 0 points, this slice will grab just the margins.
            local_t = orekit_t(idx_start:idx_end);
            local_torque = orekit_torque(:, idx_start:idx_end);
            
            % 4. Generate the target high-density time vector for adcSim
            % Using round ensures we don't drop the last sample due to floating point precision
            num_steps = round((t_end - t_ini) / Ts) + 1;
            t_dense = linspace(t_ini, t_end, num_steps);
            
            % 5. Perform the generalized interpolation
            torque_dense = zeros(3, num_steps);
            for i = 1:3
                % 'pchip' ensures shape preservation without artificial oscillations
                torque_dense(i, :) = interp1(local_t, local_torque(i, :), t_dense, 'pchip');
            end
        end

    end

    methods (Access = public)
        
         function base = mergeStructs(app, base, update)
        % MERGESTRUCTS Performs a recursive deep-merge of two structures.
        %
        %   BASE = MERGESTRUCTS(APP, BASE, UPDATE) takes the fields found 
        %   in UPDATE and applies them to the matching fields in BASE.
        %
        %   Key Features:
        %     - Preserves existing fields in BASE that are not present in UPDATE.
        %     - Recursively handles nested structures of any depth.
        %     - Filters out the 'i' identifier used for UI routing.

            % Get all field names from the incoming data
            f = fieldnames(update);
            
            for idx = 1:length(f)
                fieldName = f{idx};
                
                % Check if the current field is a nested structure
                if isstruct(update.(fieldName)) && isfield(base, fieldName)
                    
                    % Recursively merge sub-structures
                    base.(fieldName) = app.mergeStructs(base.(fieldName), update.(fieldName));
                    
                else
                    % Update terminal value, excluding the 'i' case identifier
                    if ~strcmp(fieldName, 'gui_identifier')
                        base.(fieldName) = update.(fieldName);
                    end
                end
            end
         end

        function [hx, lx, hy, ly, hz, lz] = plotFrame(app, ax, origin, q, scale, colors, labels)
        %PLOTFRAME Plot spacecraft axes in the inertial frame with custom colors and labels.
        %
        %   INPUTS:
        %       ax     - Target UIAxes.
        %       origin - [x, y, z] position of the satellite.
        %       q      - [q0, q1, q2, q3] Quaternion.
        %       scale  - Length of the arrows.
        %       colors - Cell array {colorX, colorY, colorZ}, e.g., {'r', 'g', 'b'}.
        %       labels - Cell array {labX, labY, labZ}, e.g., {'X_L', 'Y_L', 'Z_L'}.
        
            %%% Import adsim utilities
            import adcsim.utils.*
                    
            % 1. Rotate body unit axes into inertial frame (GCRF)
            % These vectors define the orientation of the frame
            x_vec = quatRotation(q, [1 0 0]');
            y_vec = quatRotation(q, [0 1 0]');
            z_vec = quatRotation(q, [0 0 1]');
        
            % 2. Plot axes using the custom colors and labels provided
            % Format for plotVector: (axes, origin, vector, scale, lineStyle, label)
            [hx, lx] = app.plotVector(ax, origin, x_vec, scale, colors{1}, labels{1});
            [hy, ly] = app.plotVector(ax, origin, y_vec, scale, colors{2}, labels{2});
            [hz, lz] = app.plotVector(ax, origin, z_vec, scale, colors{3}, labels{3});
        end

        function updateFrame(~, origin, B, scale, hx, lx, hy, ly, hz, lz)
            % UPDATEFRAME Updates 3D frame graphics with custom origin and scale.
            %
            %   Inputs:
            %       origin : [x; y; z] coordinates for the center of the frame.
            %       B      : 3x3 orientation matrix (columns are unit vectors).
            %       scale  : Scalar defining the length of the axes arrows.
            %       hx...lz: Graphic handles for arrows and labels.
        
            % Define a small offset factor for labels (10% beyond the arrow tip)
            labelOffset = 1.1;
        
            % --- Update X-Axis ---
            % Base position (XData, YData, ZData) and Vector components (U, V, W)
            set(hx, 'XData', origin(1), 'YData', origin(2), 'ZData', origin(3), ...
                    'UData', B(1,1)*scale, 'VData', B(2,1)*scale, 'WData', B(3,1)*scale);
            % Label position: origin + (direction * scale * offset)
            set(lx, 'Position', origin + B(:,1)*scale*labelOffset);
        
            % --- Update Y-Axis ---
            set(hy, 'XData', origin(1), 'YData', origin(2), 'ZData', origin(3), ...
                    'UData', B(1,2)*scale, 'VData', B(2,2)*scale, 'WData', B(3,2)*scale);
            set(ly, 'Position', origin + B(:,2)*scale*labelOffset);
        
            % --- Update Z-Axis ---
            set(hz, 'XData', origin(1), 'YData', origin(2), 'ZData', origin(3), ...
                    'UData', B(1,3)*scale, 'VData', B(2,3)*scale, 'WData', B(3,3)*scale);
            set(lz, 'Position', origin + B(:,3)*scale*labelOffset);
        end

        function [h1, h2] = plotVector(~, axesHandle, v0, v, scale, style, label)
            % PLOTVECTOR Plots a 3D vector while strictly preserving its direction.
            %
            %   This function renders a vector in 3D space starting at the satellite's
            %   absolute position (V0). Only the magnitude is modified by the SCALE 
            %   factor for visualization, ensuring the orientation (direction) 
            %   remains true to the physics of the simulation.
            %
            %   Inputs:
            %     axesHandle - Handle to the UIAxes object.
            %     v0         - 1x3 vector [x, y, z] of the satellite position (e.g., in km).
            %     v          - 1x3 unit vector [dx, dy, dz] representing direction.
            %     scale      - Magnitude factor (length of the arrow in the plot).
            %     style      - String for color and line style (e.g., 'r-').
            %     label      - Text label to be placed at the arrow's tip.
            %
            %   Outputs:
            %     h1 - Handle to the quiver3 object (the arrow).
            %     h2 - Handle to the text object (the label).
        
            % --- DIRECTION PRESERVATION LOGIC ---
            % 1. The origin (v0) is kept in absolute units to match the orbit path.
            % 2. The direction components (v) are multiplied by scale to define the tip.
            % 3. 'AutoScale' is disabled to prevent MATLAB from resizing the arrow
            %    based on other plot elements, which could mislead the user.
        
            % Draw the arrow with a fixed length and preserved direction
            h1 = quiver3(axesHandle, v0(1), v0(2), v0(3), ...
                                 v(1)*scale, v(2)*scale, v(3)*scale, ...
                                 style, 'AutoScale', 'off', 'LineWidth', 2);
            
            % Calculate the exact tip position: Tip = Origin + (Direction * Scale)
            tip_pos = v0 + (v * scale);
            
            % Place the label at the tip
            h2 = text(axesHandle, tip_pos(1), tip_pos(2), tip_pos(3), ...
                      label, 'Color', style(1), 'FontWeight', 'bold');
        end

        function [dists, mask] = getDisturbances(app, disturbances)
        % GETDISTURBANCES Orchestrates the calculation of environmental torques.
        %
        %   This function switches between a simple mathematical model (sinusoidal/constant)
        %   and a high-fidelity physical model using the Orekit propagator.
        
            import adcsim.environment.*
            
            % Upate mask parameters
            disturbances.mask = app.updateMaskParameters(app.simulationParameters);
            
            % Initialize output structure and time vector
            t = disturbances.mask.t;
            n = disturbances.mask.nSteps;

            dists.gravity_torque = zeros(3, n);
            dists.drag_torque    = zeros(3, n);
            dists.solar_torque   = zeros(3, n);
            dists.magnetic_field = zeros(3, n);
            dists.total          = zeros(3, n);
            dists.model_propagator = disturbances.model_propagator.enable;

            % --- MODE 1: SIMPLE MATHEMATICAL MODEL ---
            if disturbances.model_propagator.enable == 1
                
                % Calculate Constant Torque (Vectorized)
                if disturbances.constant.enable == 2
                    dists.total = dists.total + [disturbances.constant.Tdx; ...
                                                 disturbances.constant.Tdy; ...
                                                 disturbances.constant.Tdz]; 
                end
                
                % Calculate Variable Torque (Vectorized)
                if disturbances.variable.enable == 2
                    v = disturbances.variable;
                    dists.total = dists.total + v.A * sin([v.w1*t; v.w2*t; v.w3*t]);
                end
                
                % Update Vector time
                 dists.t = t;
                 mask.t_dense = dists.t;
                 mask.torque_dense = dists.total;
                 mask.magnetic_field = zeros(3, n);    
            
            % --- MODE 2: HIGH-FIDELITY PHYSICAL MODEL ---
            else
                
                % 1. Data Integrity Check (Run Orekit only if necessary)
                if isempty(app.simulationResults.orbital_propagation) || app.isDataOutdated
                    app.simulationResults.orbital_propagation = core.runOrekitSimulation(app.simulationParameters);
                    app.isDataOutdated = false; % Reset flag after successful run
                end
                
                % 2. Setup Physical Environment
                history = app.simulationResults.orbital_propagation;
                env_models = torque_disturbances(app.simulationParameters.spacecraft, app.simulationResults.orbital_propagation);
                N = size(history, 1);
                dists.t = history(:, 1);
                
                % 3. Define Relative Attitude (Body aligned to LVLH)
                % Assuming q = [1,0,0,0] for N steps
                q_body2lvlh = repmat([1, 0, 0, 0], N, 1);
                
                % 4. Calculate Specialized Torques (Vectorized)
                % We transpose the result to maintain the [3 x N] convention of the app
                dists.gravity_torque = env_models.gravity_torque_model(q_body2lvlh, ...
                                       app.simulationParameters.initialOrbit)';
                dists.drag_torque    = env_models.drag_torque_model()';
                dists.solar_torque   = env_models.solar_torque_model()';

                % Total Physical Torque
                dists.total = dists.gravity_torque + dists.drag_torque + dists.solar_torque;
                
                % Total Magnetic Field
                dists.magnetic_field = history(:, 23:25);

                % Get sampled data
                [mask.t_dense, mask.torque_dense] = app.get_localized_resampling(dists.t, dists.total, disturbances.mask.t_ini, disturbances.mask.t_end, disturbances.mask.Ts);
                [~, mask.magnetic_field] = app.get_localized_resampling(dists.t, dists.magnetic_field', disturbances.mask.t_ini, disturbances.mask.t_end, disturbances.mask.Ts);

            end
        end
        
        function importData(app, receivedData)
        % IMPORTDATA Synchronizes simulation parameters from external configuration windows.
        %
        % This method performs a deep-merge between 'receivedData' and the global 
        % 'app.simulationParameters' structure. It also handles specific UI 
        % refreshes for 3D visualizations when initial conditions change.
        %
        % Inputs:
        %   app          - Handle to the ADCS_program application.
        %   receivedData - Structure containing new values and a case identifier 'i'.
        
            %%% Import adsim utilities
            import adcsim.utils.*
            %%% Update dirty flag value
            app.isDataOutdated = true;

            try
                % 1. Global Data Synchronization
                % Uses recursion to update only the fields sent by the sub-window,
                % preserving other parameters in the hierarchy.
                app.simulationParameters = app.mergeStructs(app.simulationParameters, receivedData);
        
                % 2. Subsystem-Specific Triggers
                % Case 1: Initial Conditions & Orbital Elements
                if receivedData.gui_identifier == 1
                    % Re-calculate the Rotation Matrix (DCM) from the updated initial quaternion
                    B = quat2rot(app.simulationParameters.initialValues.q0');
                    
                    % Update the 3D body frame handles for the animation preview
                    app.updateFrame([0,0,0]', B, 1, app.rotBody.hx, app.rotBody.lx, ...
                                            app.rotBody.hy, app.rotBody.ly, ...
                                            app.rotBody.hz, app.rotBody.lz);
                end
        
                % 3. User Feedback
                % Display a success notification using the modern App Designer alert system.
                uialert(app.UIFigure, 'Simulation parameters successfully updated.', ...
                        'Update Successful', 'Icon', 'success', 'Modal', false);
                
            catch ME
                % Error Management
                % If a field is mismatched or a utility is missing, alert the user.
                uialert(app.UIFigure, sprintf('Failed to import data: %s', ME.message), ...
                        'Import Error', 'Icon', 'error');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % STARTUPFCN  Executes when the app starts.
            %   This function initializes the simulation environment, loads default 
            %   parameters, and prepares the 3D graphical axes.
        
            % --- Environment Setup ---
            % Load Orekit library for high-fidelity orbital propagation
            import_orekit;
        
            % --- Data Initialization ---
            % Load default simulation settings from the configuration script
            app.simulationParameters = simulation_Parameters();
            
            % Initialize the results structure with pre-allocated memory
            app.simulationResults = initialize_Results(app.simulationParameters);
        
            % --- UI Synchronization: Controller & AHRS ---
            % Map internal parameter values to the App Designer UI components
            app.controller_popupmenu.ValueIndex = app.simulationParameters.controller.selector;
            app.ADS_DropDown.Value              = app.simulationParameters.ahrs.flag;
            app.DisableAHRSCheckBox.Value       = ~app.simulationParameters.ahrs.enable;
            app.DisableAHRSfeedbackCheckBox.Value = ~app.simulationParameters.ahrs.enable_feedback;
            app.IMUsophCheckBox.Value           = ~app.simulationParameters.sensors_selector.imu.flag;
            app.EnablemagnetorquersCheckBox.Value = app.simulationParameters.mtq.enable;
            % --- UI Synchronization: Simulation Time ---
            app.finalTime_edit.Value = app.simulationParameters.sim.tf;
            app.initTime_edit.Value  = app.simulationParameters.sim.t0;
            app.step_edit.Value      = app.simulationParameters.sim.step;
            
            % --- 3D Graphics Initialization ---
            % Configure the animation axes (Graphic2)
            hold(app.graphic2_axes, 'on');
            axis(app.graphic2_axes, 'equal');
            app.graphic2_axes.XLim = [-1, 1];
            app.graphic2_axes.YLim = [-1, 1];
            app.graphic2_axes.ZLim = [-1, 1];
        
            % 1. Plot the Reference Frame (LVLH)
            % This represents the target orientation for the CubeSat.
            origin_lvlh = zeros(1, 3);
            q_identity  = [1, 0, 0, 0]'; % Identity quaternion (reference alignment)
            scale_i     = 1;
            colors_i    = {'b--', 'b--', 'b--'}; % Blue dashed lines for reference
            labels_i    = {'X_{lvlh}', 'Y_{lvlh}', 'Z_{lvlh}'};
            app.plotFrame(app.graphic2_axes, origin_lvlh, q_identity, scale_i, colors_i, labels_i);
        
            % 2. Plot the Spacecraft Body Frame
            % This represents the initial orientation of the CubeSat.
            origin_body = zeros(1, 3);
            q_initial   = app.simulationParameters.initialValues.q0;
            scale_b     = 1;
            colors_b    = {'r', 'r', 'r'}; % Solid red lines for the body frame
            labels_b    = {'X_B', 'Y_B', 'Z_B'};
            
            % Draw the frame and capture handles for future animation updates
            [hx, lx, hy, ly, hz, lz] = app.plotFrame(app.graphic2_axes, origin_body, ...
                                                     q_initial, scale_b, colors_b, labels_b);
            
            % Store graphics handles in the app object for real-time manipulation
            app.rotBody.hx = hx; app.rotBody.lx = lx;
            app.rotBody.hy = hy; app.rotBody.ly = ly;
            app.rotBody.hz = hz; app.rotBody.lz = lz;
        end

        % Button pushed function: cubesat_button
        function cubesat_buttonButtonPushed(app, event)
           app.callSatParameters = cubesatSettings(app, app.simulationParameters.spacecraft, app.simulationParameters.initialValues, app.simulationParameters.initialOrbit);
        end

        % Button pushed function: perturbation_button
        function perturbation_buttonButtonPushed(app, event)
            % PERTURBATION_BUTTONBUTTONPUSHED Handles the logic for the Perturbations UI.
            %   This callback synchronizes the simulation time, computes the expected 
            %   environmental torques (Gravity Gradient, Magnetic, etc.), and opens 
            %   the specialized settings window.
        
            % --- Data Synchronization ---
            % Read and validate the current simulation time settings from the main UI.
            % This ensures the disturbance preview matches the defined time span.
            [tResults, tSettings] = getCurrentTimeSettings(app);
            app.simulationResults.adcSim = app.mergeStructs(app.simulationResults.adcSim, tResults);
            app.simulationParameters.sim = app.mergeStructs(app.simulationParameters.sim, tSettings);
            
            % Update mask parameters
            app.simulationParameters.disturbances.mask = app.updateMaskParameters(app.simulationParameters);

            % --- Environmental Modeling ---
            % Calculate disturbance torques based on the selected propagator or 
            % mathematical models. The results are stored in the results structure 
            % to be passed to the sub-window for visualization.
            [app.simulationResults.disturbances.torque, app.simulationResults.disturbances.mask] = ...
                app.getDisturbances(...
                app.simulationParameters.disturbances);
            
            % --- UI Navigation ---
            % Open the 'perturbationSettings' window, passing the main app handle, 
            % current parameters, and the calculated results for preview/edit.
            % This window allows the user to toggle specific torque models.
            app.callDisturbances = perturbationSettings(app, ...
                                   app.simulationParameters, ...
                                   app.simulationResults);
        end

        % Button pushed function: controller_button
        function controller_buttonButtonPushed(app, event)
            v = app.controller_popupmenu.ValueIndex;
           
            switch v
                case 1
                    feedbackSettings(app,app.simulationParameters.setPoint,app.simulationParameters.controller.feedback);
                case 2
                    boskovicSettings(app,app.simulationParameters.setPoint,app.simulationParameters.controller.boskController);
                otherwise
                    disp('other value')
            end
        end

        % Button pushed function: run_button
        function run_buttonButtonPushed(app, event)
            % RUN_BUTTONBUTTONPUSHED Main execution trigger for the ADCS simulation.
            %   This callback orchestrates the data flow between the UI, the environmental 
            %   models, and the numerical RK4 integrator. It also handles post-processing 
            %   of performance indices (KPIs) and visualization.
        
            % --- 1. UI Feedback: Locking the Interface ---
            % Change cursor to 'watch' and disable the button to prevent re-entrancy 
            % and UI freezing during heavy numerical computation.
            app.UIFigure.Pointer = 'watch';
            app.run_button.Enable = 'off';
            drawnow; % Force UI to update before entering the simulation loop
        
            % --- 2. Data Preparation & Synchronization ---
            % Merge current time settings from UI into the results structure 
            % without overwriting existing simulation metadata.
            [tResults, tSettings] = getCurrentTimeSettings(app);
            app.simulationResults.adcSim = app.mergeStructs(app.simulationResults.adcSim, tResults);
            app.simulationParameters.sim = app.mergeStructs(app.simulationParameters.sim, tSettings);
            
            % Validation: Stop if time vector generation failed
            if isempty(app.simulationResults.adcSim.t)
                app.run_button.Enable = 'on';
                app.UIFigure.Pointer = 'arrow';
                return; 
            end
        
            % --- 3. Environmental Force Modeling ---
            % Compute the environmental torque array (Disturbances) for the entire 
            % simulation time span.
            [app.simulationResults.disturbances.torque, app.simulationResults.disturbances.mask] = ...
                app.getDisturbances(app.simulationParameters.disturbances);
        
            % Sync UI dropdown selections into the parameters structure
            app.simulationParameters.controller.selector = app.controller_popupmenu.ValueIndex;
            app.simulationParameters.ahrs.flag = app.ADS_DropDown.Value;
        
            % --- 4. Numerical Integration (RK4 Core) ---
            % Execute the main simulation engine. 
            % Inputs: Configuration parameters and pre-calculated disturbances.
            [sim.x, sim.u, sim.torque, sim.x_est, sim.index, sim.sensors, ...
             sim.actuators, sim.error_flag] = core.simulation_rk4(...
                                             app.simulationParameters, app.simulationResults);
            
            % Merge simulation outputs into the persistent results structure
            app.simulationResults.adcSim = app.mergeStructs(app.simulationResults.adcSim, sim);
        
            % --- 5. UI Restoration & Initial Visualization ---
            app.run_button.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
            
            % Reset Plotting Menu to default view (Euler Angles)
            app.graphic1_popupmenu.Enable = "on";
            app.graphic1_popupmenu.ValueIndex = 1;
           
            % Initial Data Rendering: Convert Quaternions to Euler [ZYX] for intuitive analysis
            euler = 180/pi * quat2eul(app.simulationResults.adcSim.x(1:4,:)', 'ZYX')';
            t = app.simulationResults.adcSim.t;
            app.universalPlot(t, euler, {'Roll', 'Pitch', 'Yaw'}, 'Angles (deg)', true);
            
            % Unlock 3D Animation playback
            app.graphic2_run_button.Enable = "on";
            
            % --- 6. Performance Metrics (KPI) Post-Processing ---
            % If simulation was stable, update the Controller Performance Panel
            if sim.error_flag == 0
                % Formatting metrics in Scientific Notation for academic precision
                app.settime_text.Text   = num2str(mean(sim.index.ts),'%.2E');
                app.eulerint_text.Text  = num2str(sim.index.eulerInt(end-1),'%.2E');
                app.ascct_text.Text     = num2str(sim.index.ascct(end-1),'%.2E');
                app.compCost_text.Text  = num2str(mean(sim.index.o(2:end)),'%.2E');
                
                % RMSE update (only if Attitude Determination is enabled)
                if app.simulationParameters.ahrs.enable
                    app.RMSE_Label.Text = ['RMSE: ', num2str(sim.index.RMSE,'%.2E'), ' deg.'];
                end
            else
                % Handle unstable simulation states
                [app.settime_text.Text, app.eulerint_text.Text, ...
                 app.ascct_text.Text, app.compCost_text.Text] = deal('N/A');
                warndlg('Unstable simulation: Controller gains might be incorrect.', 'Simulation Error');
            end
        end

        % Value changed function: graphic1_popupmenu
        function graphic1_popupmenuValueChanged(app, event)
            % GRAPHIC1_POPUPMENUVALUECHANGED Updates the main UIAxes based on user selection.
            % Using the universalPlot engine to maintain consistency across all data types.
        
            % --- Data Preparation ---
            sim    = app.simulationResults.adcSim;
            t      = app.simulationResults.adcSim.t;
            val    = app.graphic1_popupmenu.ValueIndex;
            num_rw = app.simulationParameters.rw.number;
        
            % --- Selection Logic ---
            switch val
                case 1 % Euler Angles (ZYX)
                    % Convert quaternions to Euler angles for visualization
                    euler_deg = (180/pi) * quat2eul(sim.x(1:4,:)', 'ZYX')'; 
                    app.universalPlot(t, euler_deg, {'Roll', 'Pitch', 'Yaw'}, 'Euler Angles (deg)', true);
        
                case 2 % Quaternions
                    app.universalPlot(t, sim.x(1:4,:), {'q0','q1','q2','q3'}, 'Quaternions', false);
        
                case 3 % Angular Rates Error
                    app.universalPlot(t, sim.x(5:7,:), {'\omega_x','\omega_y','\omega_z'}, 'Angular Rates Error (rad/s)', true);
        
                case 4 % Disturbance Torques
                    % Ensure we access the 'total' disturbance field
                    if app.simulationParameters.disturbances.model_propagator.enable == 1
                        td = app.simulationResults.disturbances.torque.total; % External disturbance torque.
                    else
                        td = app.simulationResults.disturbances.mask.torque_dense; % Environmental disturbance torque.
                    end
                    app.universalPlot(t, td, {'T_{dx}','T_{dy}','T_{dz}'}, 'Disturbance Torques (Nm)', false);
        
                case 5 % Torque Command
                    app.universalPlot(t, sim.u, {'u_x','u_y','u_z'}, 'Torque Command (Nm)', false);
        
                case 6 % Control Torque (Commanded vs Real)
                    % Merge commanded and real torque for side-by-side comparison
                    data_torque = [sim.torque; sim.actuators.torque_real];
                    labels = [arrayfun(@(i) sprintf('Cmd RW %d', i), 1:num_rw, 'UniformOutput', false), ...
                              arrayfun(@(i) sprintf('Real RW %d', i), 1:num_rw, 'UniformOutput', false)];
                    app.universalPlot(t, data_torque, labels, 'Control Torque (Nm)', false);
        
                case 7 % Reaction Wheels Angular Rates
                    % Comparison between commanded speed (w_cmd) and actual state (x_rw speed)
                    data_w = [sim.actuators.w_cmd; sim.actuators.x_rw(1:2:end, :)];
                    labels = [arrayfun(@(i) sprintf('Cmd Speed %d', i), 1:num_rw, 'UniformOutput', false), ...
                              arrayfun(@(i) sprintf('Actual Speed %d', i), 1:num_rw, 'UniformOutput', false)];
                    app.universalPlot(t, data_w, labels, 'RW Rates (rad/s)', false);
        
                case 8 % RW Currents
                    data_curr = sim.actuators.x_rw(2:2:end, :);
                    labels = arrayfun(@(i) sprintf('Current RW %d', i), 1:num_rw, 'UniformOutput', false);
                    app.universalPlot(t, data_curr, labels, 'RW Current (A)', false);
        
                case 9 % RW Voltages
                    labels = arrayfun(@(i) sprintf('Voltage RW %d', i), 1:num_rw, 'UniformOutput', false);
                    app.universalPlot(t, sim.actuators.u_rw, labels, 'Motor Voltage (V)', false);
        
                case 10 % Earth Magnetic field (Body frame)
                    mag_field = app.simulationResults.disturbances.mask.magnetic_field;
                    app.universalPlot(t, mag_field, {'B_x','B_y','B_z'}, 'Magnetic field (Tesla)', false);
                
                case 11 % Magnetorquer Command (Dipole Moment)
                    % Displays the commanded magnetic dipole moment (A·m²)
                    if isfield(sim.actuators, 'u_mtq_cmd')
                        data_m = sim.actuators.u_mtq_cmd;
                        labels = arrayfun(@(i) sprintf('m_{cmd} %d', i), 1:size(data_m, 1), 'UniformOutput', false);
                        app.universalPlot(t, data_m, labels, 'Dipole Moment (A\cdot m^2)', false);
                    end
            
                case 12 % Magnetic Torque (Produced)
                    % The actual torque generated by the m x B interaction
                    if isfield(sim.actuators, 'torque_mag')
                        app.universalPlot(t, sim.actuators.torque_mag, {'T_{mx}','T_{my}','T_{mz}'}, 'Magnetic Torque (Nm)', false);
                    end
            
                case 13 % MTQ Currents
                    % Electrical current consumed by the coils (Amperes)
                    if isfield(sim.actuators, 'current_mtq')
                        data_curr = sim.actuators.current_mtq;
                        labels = arrayfun(@(i) sprintf('Current MTQ %d', i), 1:size(data_curr, 1), 'UniformOutput', false);
                        app.universalPlot(t, data_curr, labels, 'MTQ Current (A)', false);
                    end
                case 14 % EULERINT (Performance Index)
                    % Time vector might be shorter if index uses diff/interp
                    t_idx = t(1:size(sim.index.eulerInt', 2));
                    app.universalPlot(t_idx, sim.index.eulerInt', {'EulerInt'}, 'EULERINT (rad\cdot s)', false);
        
                case 15 % ASCCT (Performance Index)
                    app.universalPlot(t, sim.index.ascct, {'ASCCT'}, 'ASCCT (Nm)', false);
        
                case 16 % Computational Cost
                    t_idx = t(1:size(sim.index.o, 2));
                    app.universalPlot(t_idx, sim.index.o, {'Computational Cost'}, 'Time per Step (s)', false);
        
                otherwise
                    fprintf('Unknown plot index: %d\n', val);
            end
        end

        % Button pushed function: graphic2_run_button
        function graphic2_run_buttonButtonPushed(app, event)
            %%%Enable stop animation button
            app.graphic2_stop_button.Enable = "on";
            app.graphic2_run_button.Enable  = "off";
            %%%Run animation
            app.animation(app.simulationResults.adcSim.x, app.rotBody.hx, app.rotBody.lx, app.rotBody.hy, app.rotBody.ly, app.rotBody.hz, app.rotBody.lz);
          
        end

        % Button pushed function: sensors_button
        function sensors_buttonButtonPushed(app, event)
            sensorsSettings(app,app.simulationParameters.sensors);
        end

        % Value changed function: DisableAHRSCheckBox
        function DisableAHRSCheckBoxValueChanged(app, event)
            value = app.DisableAHRSCheckBox.Value;
            if value == 1
            %     app.Ekf_parameters_button.Enable = "off";
            %     app.sensors_button.Enable = "off";
                 app.simulationParameters.ahrs.enable = 0;
            else
            %     app.Ekf_parameters_button.Enable = "on";
            %     app.sensors_button.Enable = "on"; 
                 app.simulationParameters.ahrs.enable = 1;
            end
        end

        % Button pushed function: Ekf_parameters_button
        function Ekf_parameters_buttonButtonPushed(app, event)
            EKFevaluator(app,app.simulationParameters,app.simulationResults);
        end

        % Button pushed function: RWConfigurationButton
        function RWConfigurationButtonPushed(app, event)
            reactionWheelsConf(app,app.simulationParameters);
        end

        % Button pushed function: MoreSettingsButton
        function MoreSettingsButtonPushed(app, event)
            moreSettings(app,app.simulationParameters)
        end

        % Button pushed function: AutotuningButton
        function AutotuningButtonPushed(app, event)
            % AUTOTUNINGBUTTONPUSHED Optimizes controller gains using Genetic Algorithms.
            %   This function temporarily disables AHRS to find ideal gains under 
            %   perfect state knowledge, then applies the optimal set to the main app.
            
            import optimization.*
            
            % --- 1. UI Feedback: Locking the Interface ---
            app.UIFigure.Pointer = 'watch';
            app.AutotuningButton.Enable = 'off';
            drawnow;           
           
            try
                % --- 2. Temporal Data Isolation ---
                % We use copies to avoid corrupting current app state during GA iterations
                temp_results = app.simulationResults;
                temp_parameters = app.simulationParameters;
                
                % Sync time settings for the optimization run
                temp_results.adcSim = app.mergeStructs(temp_results.adcSim, app.getCurrentTimeSettings());
                
                if isempty(temp_results.adcSim.t)
                    % Restore UI before exiting
                    app.AutotuningButton.Enable = 'on';
                    app.UIFigure.Pointer = 'arrow';
                    return; 
                end
        
                % --- 3. Environmental Setup ---
                % Calculate disturbances for the GA objective function evaluation
                [temp_results.disturbances.torque, temp_results.disturbances.mask] = ...
                    app.getDisturbances(temp_parameters.disturbances, temp_results.adcSim);
             
                % Configure optimization constraints: Select controller and bypass AHRS
                v = app.controller_popupmenu.ValueIndex; % Defined 'v' here (was missing)
                temp_parameters.controller.selector = v;
                %temp_parameters.ahrs.enable = 0; % Assuming perfect state for gain tuning
                
                % --- 4. Optimization Core (Genetic Algorithm) ---
                % 
                optimal_gains = optimize_control_gains_ga(temp_parameters, temp_results);
                
                % --- 5. Apply Optimal Results to Main Structure ---
                if v == 1
                    % Mapping GA output to Feedback Controller (Diagonal Gain Matrices)
                    app.simulationParameters.controller.feedback.Peye = diag(optimal_gains(1:3));
                    app.simulationParameters.controller.feedback.Keye = diag(optimal_gains(4:6));
                else
                    % Mapping GA output to Boskovic Adaptive Controller parameters
                    app.simulationParameters.controller.boskController.delta = optimal_gains(1);
                    app.simulationParameters.controller.boskController.gamma = optimal_gains(2); 
                    app.simulationParameters.controller.boskController.k0    = optimal_gains(3);
                end
                
                % Success notification
                uialert(app.UIFigure, 'Optimization complete. Optimal gains have been applied.', ...
                        'Auto-tuning Success', 'Icon', 'success');
        
            catch ME
                % Error handling for optimization failures
                uialert(app.UIFigure, ['Optimization failed: ', ME.message], 'Error', 'Icon', 'error');
            end
        
            % --- 6. UI Restoration ---
            app.AutotuningButton.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: AutoTuningButton
        function AutoTuningButtonPushed(app, event)
            % AUTOTUNINGBUTTONPUSHED Optimizes EKF noise covariance matrices (Q & R).
            %   This function isolates the simulation environment to find the optimal
            %   standard deviations for sensors (Gyro, Acc, Mag, Star) that minimize
            %   estimation error (RMSE).
        
            import optimization.*
        
            % --- 1. UI Feedback: Locking the Interface ---
            app.UIFigure.Pointer = 'watch';
            app.AutoTuningButton.Enable = 'off'; 
            drawnow;
        
            try
                % --- 2. Temporal Data Isolation ("Sandboxing") ---
                % Create local copies to prevent corrupting the main app state 
                % in case the optimization fails or is canceled.
                temp_params = app.simulationParameters;
                temp_results = app.simulationResults;
        
                % --- 3. Environmental Setup ---
                % Sync time settings safely using mergeStructs
                time_settings = app.getCurrentTimeSettings();
                temp_results.adcSim = app.mergeStructs(temp_results.adcSim, time_settings);
        
                % Validate time vector
                if isempty(temp_results.adcSim.t)
                    % Restore UI and exit if time is invalid
                    app.AutoTuningButton.Enable = 'on';
                    app.UIFigure.Pointer = 'arrow';
                    return; 
                end
        
                % Pre-calculate disturbances (Gravity, Magnetic Field, etc.)
                % This is crucial for the EKF to have a valid reference model.
                [temp_results.disturbances.torque, temp_results.disturbances.mask] = app.getDisturbances(...
                    temp_params.disturbances, temp_results.adcSim);
        
                % --- 4. Optimization Core (Genetic Algorithm) ---      
                % Call the optimization function passing the isolated structures
                optimal_params = optimize_ahrs_ga(temp_params, temp_results);
        
                % --- 5. Apply Optimal Results to Main Structure ---
                % We disable 'equalModel' to allow specific tuning per axis/sensor
                app.simulationParameters.ahrs.ekf.equalModel = 0;
        
                % Map the flat vector from GA back to the structured parameters
                % Indices: 1-3 (Gyro), 4-6 (Acc), 7-9 (Mag)
                app.simulationParameters.ahrs.ekf.gyro_std = optimal_params(1:3)';
                app.simulationParameters.ahrs.ekf.acc_std  = optimal_params(4:6)';
                app.simulationParameters.ahrs.ekf.mag_std  = optimal_params(7:9)';
        
                % Conditional update for Star Sensor if enabled
                if temp_params.sensors.star.enable == 1
                    app.simulationParameters.ahrs.ekf.star.std = optimal_params(10:12)';
                end
                
                % Success Notification
                uialert(app.UIFigure, 'EKF calibration complete. Optimal noise parameters applied.', ...
                        'Calibration Success', 'Icon', 'success');
        
            catch ME
                % --- Error Handling ---
                uialert(app.UIFigure, ['EKF Tuning Failed: ', ME.message], ...
                        'Optimization Error', 'Icon', 'error');
            end
        
            % --- 6. UI Restoration ---
            app.AutoTuningButton.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
            if isprop(app, 'OptStatusLabel')
                app.OptStatusLabel.Text = 'Idle';
            end
        end

        % Button pushed function: BrushlessMotorsButton
        function BrushlessMotorsButtonPushed(app, event)
            reactionWheels_parameters(app,app.simulationParameters);
        end

        % Button pushed function: PropagateOrbitButton
        function PropagateOrbitButtonPushed(app, event)
        % PROPAGATEORBITBUTTONPUSHED Manages the high-fidelity orbital propagation process.
        % This callback triggers the Orekit-based propagator to compute the spacecraft's 
        % trajectory (position, velocity, and ephemeris) before running the ADCS simulation.
    
        % 1. Data Validity Check (Optimization)
        % Only run the heavy simulation if results are empty or if parameters have changed (dirty flag)
        if isempty(app.simulationResults.orbital_propagation) || app.isDataOutdated
            
            % --- UI Feedback: Start Processing ---
            % Change cursor to 'watch' and disable button to prevent multiple clicks
            app.UIFigure.Pointer = 'watch';
            app.PropagateOrbitButton.Enable = 'off';
            drawnow; % Force UI update to show the loading cursor
            
            % --- Core Execution ---
            % Execute the Orekit simulation engine. 
            % This calculates the spacecraft's state vectors (GCRF/ITRF) and environmental data.
            app.simulationResults.orbital_propagation = core.runOrekitSimulation(app.simulationParameters);
            
            % Reset the 'dirty' flag since the stored data now matches current parameters
            app.isDataOutdated = false;
            
            % --- UI Feedback: End Processing ---
            app.PropagateOrbitButton.Enable = 'on';
            app.UIFigure.Pointer = 'arrow';
        else
            % Use cached data if nothing has changed, saving computational time
            disp('Using existing simulation data stored in app.simulationResults.orbital_propagation.');
        end
        
        % --- Visualization ---
        % Open the orbital plotting window to visualize the calculated trajectory (Ground track, 3D orbit)
        orbit_propagation(app, app.simulationParameters, app.simulationResults.orbital_propagation);
        end

        % Value changed function: DisableAHRSfeedbackCheckBox
        function DisableAHRSfeedbackCheckBoxValueChanged(app, event)
            value = app.DisableAHRSfeedbackCheckBox.Value;
            if value == 1
                 app.simulationParameters.ahrs.enable_feedback = 0;
            else
                 app.simulationParameters.ahrs.enable_feedback = 1;
            end
        end

        % Value changed function: IMUsophCheckBox
        function IMUsophCheckBoxValueChanged(app, event)
            value = app.IMUsophCheckBox.Value;
            if value == 1
                 app.simulationParameters.sensors_selector.imu.flag = 0;
            else
                 app.simulationParameters.sensors_selector.imu.flag = 1;
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            % SAVERESULTSBUTTONPUSHED Exports both simulation results and input parameters.
            % This ensures full traceability for academic papers and PhD thesis verification.
        
            % 1. Bundle results and parameters into a temporary structure
            dataToSave.results    = app.simulationResults;
            dataToSave.parameters = app.simulationParameters;
            
            % 2. Generate a default filename with a timestamp
            % Format: ADCS_FullData_YYYY-MM-DD_HHMM.mat
            timeStamp = datestr(now, 'yyyy-mm-dd_HHMM');
            defaultName = sprintf('ADCS_Full_Session_%s.mat', timeStamp);
            [file, path] = uiputfile('*.mat', 'Save Full Simulation Session', defaultName);
            
            if isequal(file, 0), return; end % User cancelled
            
            try
                % 3. Save using the '-struct' flag
                % This stores 'results' and 'parameters' as top-level variables in the .mat file.
                save(fullfile(path, file), '-struct', 'dataToSave', '-v7.3');
                
                % 4. UI Confirmation
                uialert(app.UIFigure, 'Simulation results and parameters saved successfully.', ...
                        'Export Complete', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ['Error saving session data: ', ME.message], ...
                        'Save Error', 'Icon', 'error');
            end
        end

        % Button pushed function: ImportResultsButton
        function ImportResultsButtonPushed(app, event)
            % IMPORTRESULTSBUTTONPUSHED Loads a previously saved ADCS simulation session.
            % Supports integrated format (Results + Parameters) and legacy formats.
        
            % 1. Open file dialog
            [file, path] = uigetfile('*.mat', 'Select ADCS Simulation Session');
            if isequal(file, 0), return; end 
        
            try
                % 2. Load and Extract Data
                tempData = load(fullfile(path, file));
                
                if isfield(tempData, 'results') && isfield(tempData, 'parameters')
                    % Standard session format (Best for PhD traceability)
                    importedResults = tempData.results;
                    importedParams  = tempData.parameters;
                elseif isfield(tempData, 'adcSim')
                    % Format from direct structure save
                    importedResults = tempData; 
                    importedParams  = [];
                else
                    error('Invalid file format. Structure does not match ADCSim standards.');
                end
        
                % 3. Validate Dimensions
                if size(importedResults.adcSim.x, 1) ~= 7
                    error('State vector mismatch. Expected 7 rows (Quaternions + Omega).');
                end
        
                % 4. Update Application Data
                app.simulationResults = importedResults;
                
                % 5. UI and Parameters Synchronization
                if ~isempty(importedParams)
                    app.simulationParameters = importedParams;
                    app.refreshUIFromParameters(); % Sync edit fields and 3D preview
                    statusMsg = 'Results and parameters imported successfully.';
                else
                    statusMsg = 'Results imported (No parameters found in file).';
                end
                
                % 6. Auto-Refresh Plots
                app.graphic1_popupmenu.Enable = "on";
                app.graphic1_popupmenu.ValueIndex = 1; % Reset to Euler Angles
                app.graphic1_popupmenuValueChanged();  % Trigger plotting engine
                app.graphic2_run_button.Enable = "on"; % Enable 3D animation
                
                % 7. Update Controller performance parameters
                if app.simulationResults.adcSim.error_flag == 0
                    app.settime_text.Text   = num2str(mean(app.simulationResults.adcSim.index.ts),'%.2E');
                    app.eulerint_text.Text  = num2str(app.simulationResults.adcSim.index.eulerInt(end-1),'%.2E');
                    app.ascct_text.Text     = num2str(app.simulationResults.adcSim.index.ascct(end-1),'%.2E');
                    app.compCost_text.Text  = num2str(mean(app.simulationResults.adcSim.index.o(2:end)),'%.2E');
                    if app.simulationParameters.ahrs.enable
                        app.RMSE_Label.Text = ['RMSE: ', num2str(app.simulationResults.adcSim.index.RMSE,'%.2E'), ' deg.'];
                    end
                else
                    app.settime_text.Text   = 'N/A';
                    app.eulerint_text.Text  = 'N/A';
                    app.ascct_text.Text     = 'N/A';
                    app.compCost_text.Text  = 'N/A';
    
                    warndlg('Unstable simulation', 'Simulation Error');
                end

                uialert(app.UIFigure, statusMsg, 'Import Successful', 'Icon', 'success');
        
            catch ME
                uialert(app.UIFigure, ['Import Error: ', ME.message], 'Process Failed', 'Icon', 'error');
            end
        end

        % Value changed function: EnablemagnetorquersCheckBox
        function EnablemagnetorquersCheckBoxValueChanged(app, event)
            value = app.EnablemagnetorquersCheckBox.Value;
            app.simulationParameters.mtq.enable = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.text16 = uilabel(app.GridLayout);
            app.text16.Tag = 'text16';
            app.text16.Text = 'ADCSim: A CubeSat simulator';
            app.uipanel1 = uipanel(app.GridLayout);
            app.uipanel1.Title = 'Graph';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout7 = uigridlayout(app.uipanel1);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Simulation Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.graphic1_popupmenu = uidropdown(app.GridLayout7);
            app.graphic1_popupmenu.Items = {'Euler Angles (ZYX)', 'Quaternions', 'Angular Rates', 'Perturbations', 'Torque command', 'Control Torque', 'Reaction Wheels rates', 'Reaction Wheels currents', 'Reaction Wheel voltages', 'Magnetic Field', 'Magnetorquer Command', 'Magnetic Torque', 'Magnetorquer currents', 'EULERINT', 'ASCCT', 'O'};
            app.graphic1_popupmenu.ValueChangedFcn = createCallbackFcn(app, @graphic1_popupmenuValueChanged, true);
            app.graphic1_popupmenu.Tag = 'graphic1_popupmenu';
            app.graphic1_popupmenu.Enable = 'off';
            app.graphic1_popupmenu.Value = 'Euler Angles (ZYX)';
            app.text2 = uilabel(app.GridLayout7);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select Parameter:';
            app.RMSE_Label = uilabel(app.GridLayout7);
            app.RMSE_Label.Text = 'RMSE: 0.00 deg';
            app.uibuttongroup1 = uibuttongroup(app.GridLayout);
            app.uibuttongroup1.Title = 'Animation';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.GridLayout4 = uigridlayout(app.uibuttongroup1);
            app.graphic2_axes = uiaxes(app.GridLayout4);
            xlabel(app.graphic2_axes, 'X')
            ylabel(app.graphic2_axes, 'Y')
            zlabel(app.graphic2_axes, 'Z')
            app.graphic2_axes.XLim = [-1 1];
            app.graphic2_axes.YLim = [-1 1];
            app.graphic2_axes.ZLim = [-1 1];
            app.graphic2_axes.Tag = 'graphic2_axes';
            app.graphic2_stop_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_stop_button.Tag = 'graphic2_button';
            app.graphic2_stop_button.Enable = 'off';
            app.graphic2_stop_button.Text = 'Stop';
            app.graphic2_run_button = uibutton(app.GridLayout4, 'push');
            app.graphic2_run_button.ButtonPushedFcn = createCallbackFcn(app, @graphic2_run_buttonButtonPushed, true);
            app.graphic2_run_button.Tag = 'graphic2_button';
            app.graphic2_run_button.Enable = 'off';
            app.graphic2_run_button.Text = 'Run';
            app.TLabel = uilabel(app.GridLayout4);
            app.TLabel.Text = 'T: ';
            app.time_sLabel = uilabel(app.GridLayout4);
            app.time_sLabel.Text = '0.00 s';
            app.CubeSatAnimationLabel = uilabel(app.GridLayout4);
            app.CubeSatAnimationLabel.Text = 'CubeSat Animation';
            app.uibuttongroup7 = uibuttongroup(app.GridLayout);
            app.uibuttongroup7.Title = 'Controller Performance';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.GridLayout6 = uigridlayout(app.uibuttongroup7);
            app.text8 = uilabel(app.GridLayout6);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Settlement time (5%):';
            app.text9 = uilabel(app.GridLayout6);
            app.text9.Tag = 'text9';
            app.text9.Text = 'EULERINT:';
            app.text10 = uilabel(app.GridLayout6);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ASCCT:';
            app.text11 = uilabel(app.GridLayout6);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Computational Cost (O):';
            app.settime_text = uilabel(app.GridLayout6);
            app.settime_text.Tag = 'settime_text';
            app.settime_text.Text = '0.0';
            app.eulerint_text = uilabel(app.GridLayout6);
            app.eulerint_text.Tag = 'eulerint_text';
            app.eulerint_text.Text = '0.0';
            app.ascct_text = uilabel(app.GridLayout6);
            app.ascct_text.Tag = 'ascct_text';
            app.ascct_text.Text = '0.0';
            app.compCost_text = uilabel(app.GridLayout6);
            app.compCost_text.Tag = 'compCost_text';
            app.compCost_text.Text = '0.0';
            app.run_button = uibutton(app.GridLayout, 'push');
            app.run_button.ButtonPushedFcn = createCallbackFcn(app, @run_buttonButtonPushed, true);
            app.run_button.Tag = 'run_button';
            app.run_button.Text = 'Run ADCS Simulation';
            app.ModelSettingsPanel = uipanel(app.GridLayout);
            app.ModelSettingsPanel.Title = 'Model Settings';
            app.GridLayout2 = uigridlayout(app.ModelSettingsPanel);
            app.perturbation_button = uibutton(app.GridLayout2, 'push');
            app.perturbation_button.ButtonPushedFcn = createCallbackFcn(app, @perturbation_buttonButtonPushed, true);
            app.perturbation_button.Tag = 'perturbation_button';
            app.perturbation_button.Text = 'Perturbations Settings';
            app.cubesat_button = uibutton(app.GridLayout2, 'push');
            app.cubesat_button.ButtonPushedFcn = createCallbackFcn(app, @cubesat_buttonButtonPushed, true);
            app.cubesat_button.Tag = 'cubesat_button';
            app.cubesat_button.Text = 'CubeSat Parameters';
            app.MoreSettingsButton = uibutton(app.GridLayout2, 'push');
            app.MoreSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @MoreSettingsButtonPushed, true);
            app.MoreSettingsButton.Text = 'More Settings';
            app.AttitudeControlPanel = uipanel(app.GridLayout);
            app.AttitudeControlPanel.Title = 'Attitude Control';
            app.GridLayout3 = uigridlayout(app.AttitudeControlPanel);
            app.controller_popupmenu = uidropdown(app.GridLayout3);
            app.controller_popupmenu.Items = {'Feedbak Controller', 'Boskovic''s Controller'};
            app.controller_popupmenu.Tag = 'controller_popupmenu';
            app.controller_popupmenu.Value = 'Feedbak Controller';
            app.controller_button = uibutton(app.GridLayout3, 'push');
            app.controller_button.ButtonPushedFcn = createCallbackFcn(app, @controller_buttonButtonPushed, true);
            app.controller_button.Tag = 'controller_button';
            app.controller_button.Text = 'Settings';
            app.AutotuningButton = uibutton(app.GridLayout3, 'push');
            app.AutotuningButton.ButtonPushedFcn = createCallbackFcn(app, @AutotuningButtonPushed, true);
            app.AutotuningButton.Text = 'Auto tuning';
            app.DisableAHRSfeedbackCheckBox = uicheckbox(app.GridLayout3);
            app.DisableAHRSfeedbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableAHRSfeedbackCheckBoxValueChanged, true);
            app.DisableAHRSfeedbackCheckBox.Text = 'Disable AHRS feedback';
            app.SimulationSettingsPanel = uipanel(app.GridLayout);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.GridLayout5 = uigridlayout(app.SimulationSettingsPanel);
            app.text17 = uilabel(app.GridLayout5);
            app.text17.Tag = 'text17';
            app.text17.Text = 's';
            app.text17_2 = uilabel(app.GridLayout5);
            app.text17_2.Tag = 'text17';
            app.text17_2.Text = 's';
            app.text17_3 = uilabel(app.GridLayout5);
            app.text17_3.Tag = 'text17';
            app.text17_3.Text = 's';
            app.initTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.initTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit = uieditfield(app.GridLayout5, 'numeric');
            app.finalTime_edit.ValueDisplayFormat = '%.E';
            app.finalTime_edit.Value = 30;
            app.InitialTimeLabel_3 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_3.Text = 'Step:';
            app.step_edit = uieditfield(app.GridLayout5, 'numeric');
            app.step_edit.ValueDisplayFormat = '%.E';
            app.step_edit.Value = 0.0001;
            app.InitialTimeLabel_4 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_4.Text = 'Final time:';
            app.InitialTimeLabel_5 = uilabel(app.GridLayout5);
            app.InitialTimeLabel_5.Text = 'Initial time:';
            app.AttitudeDeterminationPanel = uipanel(app.GridLayout);
            app.AttitudeDeterminationPanel.Title = 'Attitude Determination';
            app.GridLayout2_2 = uigridlayout(app.AttitudeDeterminationPanel);
            app.sensors_button = uibutton(app.GridLayout2_2, 'push');
            app.sensors_button.ButtonPushedFcn = createCallbackFcn(app, @sensors_buttonButtonPushed, true);
            app.sensors_button.Tag = 'perturbation_button';
            app.sensors_button.Text = 'Sensor Models';
            app.Ekf_parameters_button = uibutton(app.GridLayout2_2, 'push');
            app.Ekf_parameters_button.ButtonPushedFcn = createCallbackFcn(app, @Ekf_parameters_buttonButtonPushed, true);
            app.Ekf_parameters_button.Tag = 'cubesat_button';
            app.Ekf_parameters_button.Text = 'Parameters';
            app.DisableAHRSCheckBox = uicheckbox(app.GridLayout2_2);
            app.DisableAHRSCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisableAHRSCheckBoxValueChanged, true);
            app.DisableAHRSCheckBox.Text = 'Disable AHRS';
            app.ADS_DropDown = uidropdown(app.GridLayout2_2);
            app.ADS_DropDown.Items = {'QUEST', 'REQUEST', 'MAHONY', 'MADGWICK', 'EKF', 'UKF', 'CKF'};
            app.ADS_DropDown.Value = 'MADGWICK';
            app.IMUsophCheckBox = uicheckbox(app.GridLayout2_2);
            app.IMUsophCheckBox.ValueChangedFcn = createCallbackFcn(app, @IMUsophCheckBoxValueChanged, true);
            app.IMUsophCheckBox.Text = 'IMU soph';
            app.AutoTuningButton = uibutton(app.GridLayout2_2, 'push');
            app.AutoTuningButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuningButtonPushed, true);
            app.AutoTuningButton.Text = 'Auto Tuning';
            app.ActuatorsPanel = uipanel(app.GridLayout);
            app.ActuatorsPanel.Title = 'Actuators';
            app.GridLayout8 = uigridlayout(app.ActuatorsPanel);
            app.RWConfigurationButton = uibutton(app.GridLayout8, 'push');
            app.RWConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @RWConfigurationButtonPushed, true);
            app.RWConfigurationButton.Text = 'RW Configuration';
            app.BrushlessMotorsButton = uibutton(app.GridLayout8, 'push');
            app.BrushlessMotorsButton.ButtonPushedFcn = createCallbackFcn(app, @BrushlessMotorsButtonPushed, true);
            app.BrushlessMotorsButton.Text = 'Brushless Motors';
            app.EnablemagnetorquersCheckBox = uicheckbox(app.GridLayout8);
            app.EnablemagnetorquersCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablemagnetorquersCheckBoxValueChanged, true);
            app.EnablemagnetorquersCheckBox.Text = 'Enable magnetorquers';
            app.PropagateOrbitButton = uibutton(app.GridLayout, 'push');
            app.PropagateOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateOrbitButtonPushed, true);
            app.PropagateOrbitButton.Text = 'Propagate Orbit';
            app.SaveResultsButton = uibutton(app.GridLayout, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.ImportResultsButton = uibutton(app.GridLayout, 'push');
            app.ImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportResultsButtonPushed, true);
            app.ImportResultsButton.Text = 'Import Results';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.GridLayout5.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCS_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end