classdef cubesatSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout3                    matlab.ui.container.GridLayout
        OrbitalParametersPanel         matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        TrueanomalyradEditField        matlab.ui.control.NumericEditField
        TrueanomalyradEditFieldLabel   matlab.ui.control.Label
        ArgumentofPerigeeradEditField  matlab.ui.control.NumericEditField
        ArgumentofPerigeeradEditFieldLabel  matlab.ui.control.Label
        RAANradEditField               matlab.ui.control.NumericEditField
        RAANradEditFieldLabel          matlab.ui.control.Label
        InclinationradEditField        matlab.ui.control.NumericEditField
        InclinationradEditFieldLabel   matlab.ui.control.Label
        SemimajoraxisEditField         matlab.ui.control.NumericEditField
        SemimajoraxisEditFieldLabel    matlab.ui.control.Label
        uipanel2                       matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        roll_edit                      matlab.ui.control.NumericEditField
        ratez_edit                     matlab.ui.control.NumericEditField
        ratey_edit                     matlab.ui.control.NumericEditField
        ratex_edit                     matlab.ui.control.NumericEditField
        yaw_edit                       matlab.ui.control.NumericEditField
        pitch_edit                     matlab.ui.control.NumericEditField
        text11                         matlab.ui.control.Label
        text10                         matlab.ui.control.Label
        text9                          matlab.ui.control.Label
        text8                          matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        uipanel1                       matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        SolarpressureCoefficientunitlessEditField  matlab.ui.control.NumericEditField
        SolarpressureCoefficientunitlessEditFieldLabel  matlab.ui.control.Label
        Solarpressureaream2EditField   matlab.ui.control.NumericEditField
        Solarpressureaream2EditFieldLabel  matlab.ui.control.Label
        DragcoefficientunitlessEditField  matlab.ui.control.NumericEditField
        DragcoefficientunitlessEditFieldLabel  matlab.ui.control.Label
        Dragaream2EditField            matlab.ui.control.NumericEditField
        Dragaream2EditFieldLabel       matlab.ui.control.Label
        MasskgEditField                matlab.ui.control.NumericEditField
        MasskgEditFieldLabel           matlab.ui.control.Label
        Izy_edit                       matlab.ui.control.NumericEditField
        Izx_edit                       matlab.ui.control.NumericEditField
        Iyz_edit                       matlab.ui.control.NumericEditField
        Iyx_edit                       matlab.ui.control.NumericEditField
        Ixz_edit                       matlab.ui.control.NumericEditField
        Ixy_edit                       matlab.ui.control.NumericEditField
        Izz_edit                       matlab.ui.control.NumericEditField
        Iyy_edit                       matlab.ui.control.NumericEditField
        Ixx_edit                       matlab.ui.control.NumericEditField
        text3                          matlab.ui.control.Label
        ok_button                      matlab.ui.control.Button
        text2                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    properties (Access = public)
        cubesatParameters; % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app,spacecraft, inital_values, orbital_parameters)
            %%%Initial Euler angles
            euler = quat2eul(inital_values.q0')*180/pi;
            
            %%%Update Inertia tensor
            app.Ixx_edit.Value = spacecraft.I(1,1);
            app.Ixy_edit.Value = spacecraft.I(2,1);
            app.Ixz_edit.Value = spacecraft.I(3,1);
            app.Iyx_edit.Value = spacecraft.I(1,2);
            app.Iyy_edit.Value = spacecraft.I(2,2);
            app.Iyz_edit.Value = spacecraft.I(3,2);
            app.Izx_edit.Value = spacecraft.I(1,3);
            app.Izy_edit.Value = spacecraft.I(2,3);
            app.Izz_edit.Value = spacecraft.I(3,3);
            %%%Update other parameters
            app.MasskgEditField.Value = spacecraft.mass;
            app.Dragaream2EditField.Value = spacecraft.dragArea;  
            app.DragcoefficientunitlessEditField.Value = spacecraft.dragCoeff;
            app.SolarpressureCoefficientunitlessEditField.Value = spacecraft.srpArea; 
            app.Solarpressureaream2EditField.Value = spacecraft.srpCoeff;
            %%%Update orbital Parameters
            app.SemimajoraxisEditField.Value   = orbital_parameters.a;
            app.EccentricityEditField.Value    = orbital_parameters.e;
            app.InclinationradEditField.Value  = orbital_parameters.i;
            app.RAANradEditField.Value         = orbital_parameters.raan;
            app.ArgumentofPerigeeradEditField.Value = orbital_parameters.pa;
            app.TrueanomalyradEditField.Value  = orbital_parameters.ta;
            %%%Update euler angles
            app.roll_edit.Value  = euler(1);
            app.pitch_edit.Value = euler(2);
            app.yaw_edit.Value   = euler(3);
            %%%Update angular rates
            app.ratex_edit.Value = inital_values.Wo(1);
            app.ratey_edit.Value = inital_values.Wo(2);
            app.ratez_edit.Value = inital_values.Wo(3);
        end
        
        function newData = updateParameters(app)
            %%%Update euler angles
            q0 = eul2quat(pi/180*[app.roll_edit.Value,...
                 app.pitch_edit.Value,app.yaw_edit.Value])';
            %%%Update inertia tensor
            newData.spacecraft.I(1,1) = app.Ixx_edit.Value;
            newData.spacecraft.I(2,1) = app.Ixy_edit.Value;
            newData.spacecraft.I(3,1) = app.Ixz_edit.Value; 
            newData.spacecraft.I(1,2) = app.Iyx_edit.Value;
            newData.spacecraft.I(2,2) = app.Iyy_edit.Value;
            newData.spacecraft.I(3,2) = app.Iyz_edit.Value;
            newData.spacecraft.I(1,3) = app.Izx_edit.Value;
            newData.spacecraft.I(2,3) = app.Izy_edit.Value;
            newData.spacecraft.I(3,3) = app.Izz_edit.Value;
            %%%Update other parameters
            newData.spacecraft.mass = app.MasskgEditField.Value;
            newData.spacecraft.dragArea  = app.Dragaream2EditField.Value;  
            newData.spacecraft.dragCoeff = app.DragcoefficientunitlessEditField.Value;
            newData.spacecraft.srpArea   = app.SolarpressureCoefficientunitlessEditField.Value; 
            newData.spacecraft.srpCoeff  = app.Solarpressureaream2EditField.Value;
            %%%Update orbital Parameters
            newData.initialOrbit.a = app.SemimajoraxisEditField.Value;
            newData.initialOrbit.e = app.EccentricityEditField.Value;
            newData.initialOrbit.i = app.InclinationradEditField.Value;
            newData.initialOrbit.raan = app.RAANradEditField.Value;
            newData.initialOrbit.pa = app.ArgumentofPerigeeradEditField.Value;
            newData.initialOrbit.ta = app.TrueanomalyradEditField.Value;

            %%%Update quaternions
            newData.initialValues.q0 = [q0(1); q0(2); q0(3); q0(4)];
            %%%Update angular rates
            newData.initialValues.Wo = [app.ratex_edit.Value; app.ratey_edit.Value; app.ratez_edit.Value];
            %%%Identifier
            newData.gui_identifier = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, spacecraft, initalValues, orbital_parameters)
            app.callMainApp = mainApp;
            updateInterface(app, spacecraft, initalValues, orbital_parameters);
        end

        % Button pushed function: ok_button
        function ok_buttonButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp,dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.text2 = uilabel(app.GridLayout3);
            app.text2.Tag = 'text2';
            app.text2.Text = 'CubeSat''s Parameters';
            app.ok_button = uibutton(app.GridLayout3, 'push');
            app.ok_button.ButtonPushedFcn = createCallbackFcn(app, @ok_buttonButtonPushed, true);
            app.ok_button.Tag = 'ok_button';
            app.ok_button.Text = 'Apply';
            app.uipanel1 = uipanel(app.GridLayout3);
            app.uipanel1.Title = 'Spacecraft Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.text3 = uilabel(app.GridLayout);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Inertia Tensor (Kg.m^2):';
            app.Ixx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Ixx_edit.ValueDisplayFormat = '%.2f';
            app.Ixx_edit.Value = 1;
            app.Iyy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Iyy_edit.ValueDisplayFormat = '%.2f';
            app.Iyy_edit.Value = 1;
            app.Izz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Izz_edit.ValueDisplayFormat = '%.2f';
            app.Izz_edit.Value = 1;
            app.Ixy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Ixy_edit.ValueDisplayFormat = '%.2f';
            app.Ixy_edit.Value = 1;
            app.Ixz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Ixz_edit.ValueDisplayFormat = '%.2f';
            app.Ixz_edit.Value = 1;
            app.Iyx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Iyx_edit.ValueDisplayFormat = '%.2f';
            app.Iyx_edit.Value = 1;
            app.Iyz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Iyz_edit.ValueDisplayFormat = '%.2f';
            app.Iyz_edit.Value = 1;
            app.Izx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Izx_edit.ValueDisplayFormat = '%.2f';
            app.Izx_edit.Value = 1;
            app.Izy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Izy_edit.ValueDisplayFormat = '%.2f';
            app.Izy_edit.Value = 1;
            app.MasskgEditFieldLabel = uilabel(app.GridLayout);
            app.MasskgEditFieldLabel.Text = 'Mass (kg):';
            app.MasskgEditField = uieditfield(app.GridLayout, 'numeric');
            app.MasskgEditField.ValueDisplayFormat = '%5.2f';
            app.Dragaream2EditFieldLabel = uilabel(app.GridLayout);
            app.Dragaream2EditFieldLabel.Text = 'Drag area (m^2):';
            app.Dragaream2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dragaream2EditField.ValueDisplayFormat = '%5.2f';
            app.DragcoefficientunitlessEditFieldLabel = uilabel(app.GridLayout);
            app.DragcoefficientunitlessEditFieldLabel.Text = 'Drag coefficient (unitless):';
            app.DragcoefficientunitlessEditField = uieditfield(app.GridLayout, 'numeric');
            app.DragcoefficientunitlessEditField.ValueDisplayFormat = '%5.2f';
            app.Solarpressureaream2EditFieldLabel = uilabel(app.GridLayout);
            app.Solarpressureaream2EditFieldLabel.Text = 'Solar pressure area (m^2):';
            app.Solarpressureaream2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Solarpressureaream2EditField.ValueDisplayFormat = '%5.2f';
            app.SolarpressureCoefficientunitlessEditFieldLabel = uilabel(app.GridLayout);
            app.SolarpressureCoefficientunitlessEditFieldLabel.Text = 'Solar pressure Coefficient (unitless):';
            app.SolarpressureCoefficientunitlessEditField = uieditfield(app.GridLayout, 'numeric');
            app.SolarpressureCoefficientunitlessEditField.ValueDisplayFormat = '%5.2f';
            app.uipanel2 = uipanel(app.GridLayout3);
            app.uipanel2.Title = 'Initial Conditions';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout2 = uigridlayout(app.uipanel2);
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'X';
            app.text8 = uilabel(app.GridLayout2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Y';
            app.text9 = uilabel(app.GridLayout2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Z';
            app.text10 = uilabel(app.GridLayout2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Euler Angles (deg):';
            app.text11 = uilabel(app.GridLayout2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Angular Rate (deg/s):';
            app.pitch_edit = uieditfield(app.GridLayout2, 'numeric');
            app.pitch_edit.ValueDisplayFormat = '%.2f';
            app.pitch_edit.Value = 1;
            app.yaw_edit = uieditfield(app.GridLayout2, 'numeric');
            app.yaw_edit.ValueDisplayFormat = '%.2f';
            app.yaw_edit.Value = 1;
            app.ratex_edit = uieditfield(app.GridLayout2, 'numeric');
            app.ratex_edit.ValueDisplayFormat = '%.2f';
            app.ratex_edit.Value = 1;
            app.ratey_edit = uieditfield(app.GridLayout2, 'numeric');
            app.ratey_edit.ValueDisplayFormat = '%.2f';
            app.ratey_edit.Value = 1;
            app.ratez_edit = uieditfield(app.GridLayout2, 'numeric');
            app.ratez_edit.ValueDisplayFormat = '%.2f';
            app.ratez_edit.Value = 1;
            app.roll_edit = uieditfield(app.GridLayout2, 'numeric');
            app.roll_edit.ValueDisplayFormat = '%.2f';
            app.roll_edit.Value = 20;
            app.OrbitalParametersPanel = uipanel(app.GridLayout3);
            app.OrbitalParametersPanel.Title = 'Orbital Parameters';
            app.GridLayout4 = uigridlayout(app.OrbitalParametersPanel);
            app.SemimajoraxisEditFieldLabel = uilabel(app.GridLayout4);
            app.SemimajoraxisEditFieldLabel.Text = 'Semi-major axis: ';
            app.SemimajoraxisEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SemimajoraxisEditField.ValueDisplayFormat = '%5.2f';
            app.InclinationradEditFieldLabel = uilabel(app.GridLayout4);
            app.InclinationradEditFieldLabel.Text = 'Inclination (rad):';
            app.InclinationradEditField = uieditfield(app.GridLayout4, 'numeric');
            app.InclinationradEditField.ValueDisplayFormat = '%5.2f';
            app.RAANradEditFieldLabel = uilabel(app.GridLayout4);
            app.RAANradEditFieldLabel.Text = 'RAAN (rad):';
            app.RAANradEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RAANradEditField.ValueDisplayFormat = '%5.2f';
            app.ArgumentofPerigeeradEditFieldLabel = uilabel(app.GridLayout4);
            app.ArgumentofPerigeeradEditFieldLabel.Text = 'Argument of Perigee (rad):';
            app.ArgumentofPerigeeradEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ArgumentofPerigeeradEditField.ValueDisplayFormat = '%5.2f';
            app.TrueanomalyradEditFieldLabel = uilabel(app.GridLayout4);
            app.TrueanomalyradEditFieldLabel.Text = 'True anomaly (rad):';
            app.TrueanomalyradEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TrueanomalyradEditField.ValueDisplayFormat = '%5.2f';
            app.EccentricityEditFieldLabel = uilabel(app.GridLayout4);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity:';
            app.EccentricityEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EccentricityEditField.ValueDisplayFormat = '%5.2f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cubesatSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end