classdef feedbackSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        SaveButton            matlab.ui.control.Button
        GainsPanel            matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        EditField_P_3         matlab.ui.control.NumericEditField
        EditField_P_2         matlab.ui.control.NumericEditField
        EditField_P_1         matlab.ui.control.NumericEditField
        EditField_K_3         matlab.ui.control.NumericEditField
        EditField_K_2         matlab.ui.control.NumericEditField
        EditField_K_1         matlab.ui.control.NumericEditField
        PLabel                matlab.ui.control.Label
        KLabel                matlab.ui.control.Label
        ZLabel_2              matlab.ui.control.Label
        YLabel_2              matlab.ui.control.Label
        XLabel_2              matlab.ui.control.Label
        SetPointPanel         matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        EditField_wz          matlab.ui.control.NumericEditField
        EditField_wy          matlab.ui.control.NumericEditField
        EditField_wx          matlab.ui.control.NumericEditField
        EditField_yaw         matlab.ui.control.NumericEditField
        EditField_pitch       matlab.ui.control.NumericEditField
        EditField_roll        matlab.ui.control.NumericEditField
        AngularRatedegsLabel  matlab.ui.control.Label
        EulerAnglesdegLabel   matlab.ui.control.Label
        ZLabel                matlab.ui.control.Label
        YLabel                matlab.ui.control.Label
        XLabel                matlab.ui.control.Label
        FeedBackControllerSettingsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        function updateInterface(app, data, data1)
            %%%Update setpoint quaternions
            euler = quat2eul(data.qd')*180/pi;
            app.EditField_roll.Value  = euler(1);
            app.EditField_pitch.Value = euler(2);
            app.EditField_yaw.Value   = euler(3);
            %%%Update setpoint angular rate
            %app.wx_edit.Value  = data.Wd(1);
            %app.wy_edit.Value  = data.Wd(2);
            %app.wz_edit.Value  = data.Wd(3);

            %%%Update controller parameters
            app.EditField_K_1.Value = data1.Keye(1,1);
            app.EditField_K_2.Value = data1.Keye(2,2);
            app.EditField_K_3.Value = data1.Keye(3,3);
            app.EditField_P_1.Value = data1.Peye(1,1);
            app.EditField_P_2.Value = data1.Peye(2,2);
            app.EditField_P_3.Value = data1.Peye(3,3);
        end    

        function newData = updateParameters(app)
            %%%Update euler angles
            q = eul2quat(pi/180*[app.EditField_roll.Value,...
                 app.EditField_pitch.Value,app.EditField_yaw.Value])';
            %%%Update quaternions
            newData.setPoint.qd = [q(1); q(2); q(3); q(4)];
            %%%Update angular rates
            newData.controller.feedback.Keye = diag([app.EditField_K_1.Value,app.EditField_K_2.Value,app.EditField_K_3.Value]);
            newData.controller.feedback.Peye = diag([app.EditField_P_1.Value,app.EditField_P_2.Value,app.EditField_P_3.Value]);
            newData.gui_identifier = 4;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData, mainAppData1)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData, mainAppData1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp,dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FeedBackControllerSettingsLabel = uilabel(app.GridLayout);
            app.FeedBackControllerSettingsLabel.Text = 'FeedBack Controller Settings';
            app.SetPointPanel = uipanel(app.GridLayout);
            app.SetPointPanel.Title = 'Set Point';
            app.GridLayout3 = uigridlayout(app.SetPointPanel);
            app.XLabel = uilabel(app.GridLayout3);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GridLayout3);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.GridLayout3);
            app.ZLabel.Text = 'Z';
            app.EulerAnglesdegLabel = uilabel(app.GridLayout3);
            app.EulerAnglesdegLabel.Text = 'Euler Angles (deg):';
            app.AngularRatedegsLabel = uilabel(app.GridLayout3);
            app.AngularRatedegsLabel.Text = 'Angular Rate (deg/s):';
            app.EditField_roll = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_roll.ValueDisplayFormat = '%5.2f';
            app.EditField_pitch = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_pitch.ValueDisplayFormat = '%5.2f';
            app.EditField_yaw = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_yaw.ValueDisplayFormat = '%5.2f';
            app.EditField_wx = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_wx.Enable = 'off';
            app.EditField_wy = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_wy.Enable = 'off';
            app.EditField_wz = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_wz.Enable = 'off';
            app.GainsPanel = uipanel(app.GridLayout);
            app.GainsPanel.Title = 'Gains';
            app.GridLayout2 = uigridlayout(app.GainsPanel);
            app.XLabel_2 = uilabel(app.GridLayout2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GridLayout2);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.GridLayout2);
            app.ZLabel_2.Text = 'Z';
            app.KLabel = uilabel(app.GridLayout2);
            app.KLabel.Text = 'K:';
            app.PLabel = uilabel(app.GridLayout2);
            app.PLabel.Text = 'P:';
            app.EditField_K_1 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_K_1.ValueDisplayFormat = '%e';
            app.EditField_K_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_K_2.ValueDisplayFormat = '%e';
            app.EditField_K_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_K_3.ValueDisplayFormat = '%e';
            app.EditField_P_1 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_P_1.ValueDisplayFormat = '%e';
            app.EditField_P_2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_P_2.ValueDisplayFormat = '%e';
            app.EditField_P_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_P_3.ValueDisplayFormat = '%e';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = feedbackSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end