classdef moreSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout2                     matlab.ui.container.GridLayout
        ControllerGenAlgorithmSettingsPanel  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DesiredSettlementtimesEditField  matlab.ui.control.NumericEditField
        DesiredSettlementtimesEditFieldLabel  matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        GnerationsEditField             matlab.ui.control.NumericEditField
        GnerationsEditFieldLabel        matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        OreKitSimulationParametersPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Ode45postoleranceEditField      matlab.ui.control.NumericEditField
        Ode45postoleranceEditFieldLabel  matlab.ui.control.Label
        Ode45maximunStepEditField       matlab.ui.control.NumericEditField
        Ode45maximunStepEditFieldLabel  matlab.ui.control.Label
        Ode45minimunStepEditField       matlab.ui.control.NumericEditField
        Ode45minimunStepEditFieldLabel  matlab.ui.control.Label
        StepsizeforloggingEditField     matlab.ui.control.NumericEditField
        StepsizeforloggingEditFieldLabel  matlab.ui.control.Label
        NumberoforbitsEditField         matlab.ui.control.NumericEditField
        NumberoforbitsEditFieldLabel    matlab.ui.control.Label
        AlgorithmsSampletimePanel       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        OtherSensorssEditField          matlab.ui.control.NumericEditField
        OtherSensorsLabel               matlab.ui.control.Label
        GyroscopesEditField             matlab.ui.control.NumericEditField
        GyroscopesEditFieldLabel        matlab.ui.control.Label
        ControlalgorithmsEditField      matlab.ui.control.NumericEditField
        ControlalgorithmLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app, data)
            %UPDATEINTERFACE Populates UI fields from a data structure.
            %
            %   This function acts as a bridge from the backend data to the
            %   frontend UI. It takes a data structure containing simulation
            %   parameters and uses its values to update the corresponding
            %   edit fields in the App Designer interface. This is typically
            %   called when loading settings from a file or restoring defaults.
            %
            %   Inputs:
            %       app  - The main app object, containing UI components.
            %       data - A structure containing the parameters to display.
            %              It is expected to have the fields:
            %              data.control.Ts
            %              data.sensors.gyro.Ts
            %              data.sensors.attitude.Ts

            % Update the value of each UI edit field with data from the structure.
            app.ControlalgorithmsEditField.Value = data.control.Ts;
            app.GyroscopesEditField.Value        = data.sensors.gyro.Ts;
            app.OtherSensorssEditField.Value     = data.sensors.attitude.Ts;

            % Update data from orekit
            app.NumberoforbitsEditField.Value  = data.duration.num_orbits;
            app.StepsizeforloggingEditField.Value = data.output.stepSize;
            app.Ode45minimunStepEditField.Value = data.integrator.minStep;
            app.Ode45maximunStepEditField.Value = data.integrator.maxStep;
            app.Ode45postoleranceEditField.Value = data.integrator.posTolerance;

            % Update data from GA controller
            app.PopulationSizeEditField.Value  = data.ga.controller.popultaionSize;
            app.GnerationsEditField.Value      = data.ga.controller.generations;
            app.ToleranceEditField.Value       = data.ga.controller.tolerance;
            app.DesiredSettlementtimesEditField.Value = data.ga.controller.ref_ts;
        end

        function newData = updateParameters(app)
            %UPDATEPARAMETERS Collects UI field values into a data structure.
            %
            %   This function gathers the current values from various UI
            %   edit fields and organizes them into a structured format.
            %   This is useful for saving the current state of the UI or
            %   passing the user-defined parameters to another function or app.
            %
            %   An identifier index 'i' is added to the structure to help
            %   a receiving function (like a switch-case) identify the type
            %   of data being sent.
            %
            %   Inputs:
            %       app - The main app object, containing UI components.
            %
            %   Outputs:
            %       newData - A structure containing the values read from the UI.

            % Read the value from each UI edit field and assign it to the new data structure.
            newData.control.Ts = app.ControlalgorithmsEditField.Value;
            newData.sensors.gyro.Ts = app.GyroscopesEditField.Value;
            newData.sensors.attitude.Ts = app.OtherSensorssEditField.Value;

            % Update data from orekit
            newData.duration.num_orbits = app.NumberoforbitsEditField.Value;
            newData.output.stepSize = app.StepsizeforloggingEditField.Value;
            newData.integrator.minStep = app.Ode45minimunStepEditField.Value;
            newData.integrator.maxStep = app.Ode45maximunStepEditField.Value;
            newData.integrator.posTolerance = app.Ode45postoleranceEditField.Value;
            
            % Update data from GA controller
            newData.ga.controller.popultaionSize = app.PopulationSizeEditField.Value;
            newData.ga.controller.generations    = app.GnerationsEditField.Value;
            newData.ga.controller.tolerance      = app.ToleranceEditField.Value;
            newData.ga.controller.ref_ts         = app.DesiredSettlementtimesEditField.Value;

            % Assign an identifier for this data packet.
            newData.gui_identifier = 8;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            %STARTUPFCN Initializes the app after component creation.
            %
            % This function is automatically executed when the app starts.
            % It is designed to be called by a main application, establishing
            % a parent-child relationship between the two windows.
            %
            % Its primary roles are:
            % 1. Store a handle to the main application ('mainApp') so this
            %    child window can communicate back to it (e.g., send updated
            %    parameters).
            % 2. Populate its own UI components with the initial data
            %     ('mainAppData') passed from the main app.
            %
            % Inputs:
            %  app         - The app object for this UI figure.
            %  mainApp     - A handle to the main application that launched this one.
            %  mainAppData - A data structure (e.g., a struct) containing the
            %                initial settings to display in this app's UI.

            % Store the handle to the main application in a property of this app.
            % This allows this app to call public methods of the main app later.
            app.callMainApp = mainApp;

            % Call a helper function to populate the UI fields (e.g., edit boxes,
            % sliders) with the initial data received from the main app.
            updateInterface(app, mainAppData);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %SAVEBUTTONPUSHED Callback for the 'Save' or 'Apply' button.
            %
            %   This function orchestrates the process of saving the user's
            %   changes from this settings window and returning control to
            %   the main application. It performs three key actions:
            %   1. Gathers the current values from the UI components.
            %   2. Transmits this data back to the main application.
            %   3. Closes the current settings window.
            %
            %   Inputs:
            %       app   - The app object for the current UI figure.
            %       event - Event data (not used in this function, but
            %               required by the callback signature).

            % Step 1: Collect the user-modified parameters from the UI fields
            % into a structured variable.
            dataToSend = updateParameters(app);

            % Step 2: Send the collected data back to the main application
            % by calling its public 'importData' method.
            importData(app.callMainApp, dataToSend);

            % Step 3: Close and delete the current window, as its purpose
            % (configuring and saving settings) is complete.
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.AlgorithmsSampletimePanel = uipanel(app.GridLayout2);
            app.AlgorithmsSampletimePanel.Title = 'Algorithms Sample time';
            app.GridLayout = uigridlayout(app.AlgorithmsSampletimePanel);
            app.ControlalgorithmLabel = uilabel(app.GridLayout);
            app.ControlalgorithmLabel.Text = 'Control algorithm (s): ';
            app.ControlalgorithmsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ControlalgorithmsEditField.ValueDisplayFormat = '%5.2f';
            app.GyroscopesEditFieldLabel = uilabel(app.GridLayout);
            app.GyroscopesEditFieldLabel.Text = 'Gyroscope (s):';
            app.GyroscopesEditField = uieditfield(app.GridLayout, 'numeric');
            app.GyroscopesEditField.ValueDisplayFormat = '%5.2f';
            app.OtherSensorsLabel = uilabel(app.GridLayout);
            app.OtherSensorsLabel.Text = 'Other Sensors (s):';
            app.OtherSensorssEditField = uieditfield(app.GridLayout, 'numeric');
            app.OtherSensorssEditField.ValueDisplayFormat = '%5.2f';
            app.OreKitSimulationParametersPanel = uipanel(app.GridLayout2);
            app.OreKitSimulationParametersPanel.Title = 'OreKit Simulation Parameters';
            app.GridLayout3 = uigridlayout(app.OreKitSimulationParametersPanel);
            app.NumberoforbitsEditFieldLabel = uilabel(app.GridLayout3);
            app.NumberoforbitsEditFieldLabel.Text = 'Number of orbits:';
            app.NumberoforbitsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumberoforbitsEditField.ValueDisplayFormat = '%5.2f';
            app.StepsizeforloggingEditFieldLabel = uilabel(app.GridLayout3);
            app.StepsizeforloggingEditFieldLabel.Text = 'Step size for logging:';
            app.StepsizeforloggingEditField = uieditfield(app.GridLayout3, 'numeric');
            app.StepsizeforloggingEditField.ValueDisplayFormat = '%5.2f';
            app.Ode45minimunStepEditFieldLabel = uilabel(app.GridLayout3);
            app.Ode45minimunStepEditFieldLabel.Text = 'Ode 45 minimun Step:';
            app.Ode45minimunStepEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Ode45minimunStepEditField.ValueDisplayFormat = '%5.2f';
            app.Ode45maximunStepEditFieldLabel = uilabel(app.GridLayout3);
            app.Ode45maximunStepEditFieldLabel.Text = 'Ode 45 maximun Step:';
            app.Ode45maximunStepEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Ode45maximunStepEditField.ValueDisplayFormat = '%5.2f';
            app.Ode45postoleranceEditFieldLabel = uilabel(app.GridLayout3);
            app.Ode45postoleranceEditFieldLabel.Text = 'Ode 45 pos tolerance:';
            app.Ode45postoleranceEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Ode45postoleranceEditField.ValueDisplayFormat = '%5.2f';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ControllerGenAlgorithmSettingsPanel = uipanel(app.GridLayout2);
            app.ControllerGenAlgorithmSettingsPanel.Title = 'Controller Gen Algorithm Settings';
            app.GridLayout4 = uigridlayout(app.ControllerGenAlgorithmSettingsPanel);
            app.PopulationSizeEditFieldLabel = uilabel(app.GridLayout4);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size:';
            app.PopulationSizeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GnerationsEditFieldLabel = uilabel(app.GridLayout4);
            app.GnerationsEditFieldLabel.Text = 'Gnerations';
            app.GnerationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ToleranceEditFieldLabel = uilabel(app.GridLayout4);
            app.ToleranceEditFieldLabel.Text = 'Tolerance:';
            app.ToleranceEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ToleranceEditField.ValueDisplayFormat = '%e';
            app.DesiredSettlementtimesEditFieldLabel = uilabel(app.GridLayout4);
            app.DesiredSettlementtimesEditFieldLabel.Text = 'Desired Settlement time (s):';
            app.DesiredSettlementtimesEditField = uieditfield(app.GridLayout4, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moreSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end