classdef orbit_propagation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        PlotMagneticFieldCheckBox     matlab.ui.control.CheckBox
        TimehSlider                   matlab.ui.control.Slider
        TimehSliderLabel              matlab.ui.control.Label
        OrbitpropagationresultsLabel  matlab.ui.control.Label
        OptionsDropDown               matlab.ui.control.DropDown
        OptionsDropDownLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_x                      matlab.ui.control.UIAxes
        UIAxes_y                      matlab.ui.control.UIAxes
        UIAxes_z                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    % --- Animation variables
    SatelliteMarker % Satellile markers
    LVLH_Axes       % Orbit frame axes
    trajHandle      % Satellite trajectory 

    % --- Simulation Results Storage ---
    
    callMainApp   % Handle to the main application, if needed for callbacks.
    simulationParameters  % Handle to the simulation Parameters, if needed for recalculations.

    time_vector   % (Nx1 double) Time vector for plotting, converted to hours [h].

    % --- Dynamics Data ---
    GCRFpos       % (Nx3 double) Satellite position vector history in the GCRF frame [m].
    GCRFvel       % (Nx3 double) Satellite velocity vector history in the GCRF frame [m/s].
    quatern       % (Nx4 double) Reference quaternion history (GCRF to LVLH rotation) [q0, q1, q2, q3].
    
    % --- Acceleration Data ---
    % Each property stores the (Nx3 double) history of the acceleration vector [m/s^2]
    % caused by a specific force model.
    accel_gravity % Acceleration due to Earth's gravity model (Spherical Harmonics).
    accel_drag    % Acceleration due to atmospheric drag (Harris-Priester).
    accel_sun     % Acceleration due to the Sun's third-body gravity.
    accel_moon    % Acceleration due to the Moon's third-body gravity.
    accel_srp     % Acceleration due to Solar Radiation Pressure (Isotropic).

    % --- Geomagnetic Data ---
    % Stores the history of the Earth's magnetic field vector [Tesla].
    mag_field     % (Nx3 double) Magnetic field vector in GCRF frame from IGRF model.
end

methods (Access = private)
    
    function set_slider_limits(app)
    % SET_SLIDER_LIMITS Configures the time slider based on the simulation data.
    % 
    % This function sets the slider's range to match the total propagation time,
    % initializes its position to the start, and generates exactly 5 evenly 
    % spaced major ticks for a clean visual representation of the timeline.
    
        if ~isempty(app.time_vector)
            % Define the start and end times in hours
            startTime = app.time_vector(1);
            endTime   = app.time_vector(end);
    
            % Set the slider range and initial value
            app.TimehSlider.Limits = [startTime, endTime];
            app.TimehSlider.Value  = startTime;
    
            % Generate exactly 5 major ticks distributed along the time vector
            % using linspace to ensure uniform spacing.
            app.TimehSlider.MajorTicks = linspace(startTime, endTime, 5);
        end
    end
    
    function updateOrbitLegend(app)
    % UPDATEORBITLEGEND Dynamically updates the 3D plot legend based on visible components.
    %
    %   This function manages the visibility of legend entries by checking the 
    %   validity and presence of orbital components. It ensures that only active 
    %   elements (Trajectory, LVLH Frame, Satellite, and Magnetic Field) are 
    %   displayed, maintaining a clean UI.
    %
    %   INPUTS:
    %       app - Handle to the orbit_propagation application object.
    
        % 1. Collect potential graphics handles from app properties
        % Using direct handles is faster than findobj for frequently updated plots.
        hTraj = app.trajHandle;             % Stored during plot3Dorbit
        hSat  = app.SatelliteMarker;        % Green dot handle
        hLvlh = app.LVLH_Axes.hx;           % Using X-axis as proxy for the LVLH triad
        
        % Search for the B-Field vectors using the custom Tag
        hMag  = findobj(app.UIAxes, 'Tag', 'BFieldVectors');
    
        % 2. Filter active and valid graphics objects
        % Initialize empty arrays for items and their corresponding display names.
        items  = gobjects(0); % Pre-allocating as a graphics object array for efficiency
        labels = {};
    
        % Logic Check: Trajectory
        if isgraphics(hTraj)
            items(end+1) = hTraj;
            labels{end+1} = 'Trajectory';
        end
    
        % Logic Check: LVLH Frame
        if isgraphics(hLvlh)
            items(end+1) = hLvlh;
            labels{end+1} = 'LVLH frame';
        end
    
        % Logic Check: Satellite Marker
        if isgraphics(hSat)
            items(end+1) = hSat;
            labels{end+1} = 'Satellite';
        end
    
        % Logic Check: Magnetic Field (Dynamic visibility)
        if isgraphics(hMag)
            items(end+1) = hMag(1); % Use the first handle if quiver returns an array
            labels{end+1} = 'Magnetic Field (B)';
        end
    
        % 3. Apply Legend Configuration
        if ~isempty(items)
            % Using 'best' ensures the legend doesn't overlap the Earth or satellite
            lgd = legend(app.UIAxes, items, labels);
            set(lgd, 'Location', 'best', ...
                     'TextColor', 'w'); 
        else
            % Hide legend if no relevant objects are present
            legend(app.UIAxes, 'off');
        end
    end

    function plotMagneticField(app, scale_factor)
    % PLOTMAGNETICFIELD Renders a downsampled 3D magnetic field vector map.
    %
    %   This function visualizes the Earth's magnetic field direction along the 
    %   satellite's orbital path. It automatically calculates a dynamic step size 
    %   to maintain a clean visualization (~30 vectors total) regardless of 
    %   simulation duration. All vectors are normalized to unit length to emphasize 
    %   field direction over magnitude.
    %
    %   INPUTS:
    %       app          - Handle to the orbit_propagation application object.
    %       scale_factor - Numeric value (km) defining the visual length of the vectors.
    %
    %   NOTES:
    %       - Vectors are plotted in the GCRF (inertial) frame.
    %       - Position data is converted from meters to kilometers for axis consistency.
    %       - Uses the 'BFieldVectors' Tag for efficient graphics management.
    
        % 1. Calculate the dynamic downsampling step
        % Retrieve the total number of simulated states.
        totalPoints = size(app.GCRFpos, 1);
        
        % Determine the step size to display approximately 30 vectors across the orbit.
        % Using max(1, ...) prevents division errors with small datasets.
        step = max(1, round(totalPoints / 30)); 
        
        hold(app.UIAxes, 'on');
        
        % 2. Extract downsampled trajectory and field data
        idx = 1:step:totalPoints;
        origin = app.GCRFpos(idx, :) / 1000; % Conversion: Meters to Kilometers.
        B_vec  = app.mag_field(idx, :);      % Magnetic field in GCRF [Tesla].
        
        % 3. Vector Normalization
        % Compute the L2 norm for each magnetic field vector to obtain unit direction.
        bMagnitudes = sqrt(sum(B_vec.^2, 2)); 
        B_unit = B_vec ./ bMagnitudes;
        
        % 4. Graphics Housekeeping
        % Target and remove existing field vectors to prevent UI over-saturation.
        delete(findobj(app.UIAxes, 'Tag', 'BFieldVectors'));
        
        % 5. 3D Vector Plotting
        % Render unit vectors using quiver3 with the specified visual scale.
        quiver3(app.UIAxes, origin(:,1), origin(:,2), origin(:,3), ...
                B_unit(:,1)*scale_factor, B_unit(:,2)*scale_factor, B_unit(:,3)*scale_factor, ...
                'Color', 'm', 'LineWidth', 1.2, 'AutoScale', 'off', 'Tag', 'BFieldVectors');
        hold(app.UIAxes, 'off');
    end

    function plot3Dorbit(app, ICRFdata)
    %plot3Dorbit Plots the 3D orbit trajectory on the app's main UI axes.
    %
    % This function clears the axes, then plots the propagated
    % orbit, a textured Earth sphere, and start/end markers.
    %
    %   INPUTS:
    %       app      - The main application object (from App Designer).
    %       ICRFdata - (Nx3 double) The position history matrix [m].
        
        %%% Import adsim and orekit utilities
        import adcsim.utils.*
        import org.orekit.utils.Constants;
    
        % --- Clear and Prepare Axes ---
        % Clear the axes from any previous plots and hold for new ones.
        hold(app.UIAxes, 'on');
        
        % --- Plot the Trajectory ---
        % Plot the trajectory, converting position from meters to kilometers.
        app.trajHandle = plot3(app.UIAxes, ICRFdata(:,1)/1000, ICRFdata(:,2)/1000, ICRFdata(:,3)/1000, ...
                         'Color', '#0072BD', 'LineWidth', 2); % A blue color

        %%%Plot LVLH Frame
        o_lvlh = ICRFdata(1,:)/1000;
        %q_lvlh = quaternConj(app.quatern(1,:))';
        q_lvlh = app.quatern(1,:)';
        colors_b = {'r--','r--','r--'}; 
        labels_b = {'X_{lvlh}','Y_{lvlh}','Z_{lvlh}'};
        [hx, lx, hy, ly, hz, lz] = plotFrame(app.callMainApp, app.UIAxes, o_lvlh, q_lvlh, app.LVLH_Axes.scale_lvlh, colors_b, labels_b);
        
        %%%Store Handles for Animation
        app.LVLH_Axes.hx = hx; app.LVLH_Axes.lx = lx;
        app.LVLH_Axes.hy = hy; app.LVLH_Axes.ly = ly;
        app.LVLH_Axes.hz = hz; app.LVLH_Axes.lz = lz;

        % --- Draw a Textured Earth Sphere ---
        % Create the coordinate data for a sphere.
        [x, y, z] = sphere(50); 
        
        % Get Earth's equatorial radius in kilometers from Orekit's constants.
        earth_radius_km = Constants.WGS84_EARTH_EQUATORIAL_RADIUS / 1000;
        
        % Load the Earth texture map.
        try
            cdata = imread('earth_texture.jpg');
        catch ME
            % If the image is not found, display a warning and prepare to draw a simple sphere.
            warning(ME.identifier, 'Texture file not found. Error: %s', ME.message);            
            cdata = []; % Set cdata to empty to trigger the fallback.
        end
        
        % Create the surface object for the Earth.
        if ~isempty(cdata)
            % --- CORRECTED ORIENTATION ---
            % Invert the x-axis (-x) to fix the east-west mirror effect.
            % Flip the CData vertically (flipud) to fix the north-south orientation.
            surf(app.UIAxes, x*earth_radius_km, y*earth_radius_km, z*earth_radius_km, ...
                 'FaceColor', 'texturemap', 'EdgeColor', 'none', 'CData', flipud(cdata),'FaceAlpha', 0.6);
            set(app.UIAxes, 'Color', 'k'); % Set a black background for a "space" look.
        else
            % Fallback: If no texture is available, draw a simple blue sphere.
            surf(app.UIAxes, x*earth_radius_km, y*earth_radius_km, z*earth_radius_km, ...
                 'FaceColor', 'blue', 'EdgeColor', 'none', 'FaceAlpha', 0.7);
        end
        
        % --- Plot Start and End Markers ---
        % Plot a green circle for the starting point.
        app.SatelliteMarker  = plot3(app.UIAxes, ICRFdata(1,1)/1000, ICRFdata(1,2)/1000, ICRFdata(1,3)/1000, ...
                            'go', 'MarkerFaceColor', 'g', 'MarkerSize', 8);
       
        % --- DYNAMIC LIMITS (The fix you needed) ---
        max_r = max(vecnorm(ICRFdata, 2, 2)) / 1000;
        %axis_lim = max_r + app.LVLH_Axes.scale_lvlh + 500; % Safety margin
        axis_lim = max_r + app.LVLH_Axes.scale_lvlh + 100; % Safety margin
        
        app.UIAxes.XLim = [-axis_lim, axis_lim];
        app.UIAxes.YLim = [-axis_lim, axis_lim];
        app.UIAxes.ZLim = [-axis_lim, axis_lim];
            
        % --- Graph Configuration and Labels ---
        title(app.UIAxes, 'Propagated Orbit with Orekit');
        xlabel(app.UIAxes, 'X (km)'); 
        ylabel(app.UIAxes, 'Y (km)'); 
        zlabel(app.UIAxes, 'Z (km)');
        
        % Enable the grid and set the aspect ratio to be equal (crucial for correct orbit shape).
        grid(app.UIAxes, 'on'); 
        axis(app.UIAxes, 'equal');
        
        % Add a legend to identify the plotted elements.
        %legend(app.UIAxes, 'Trajectory', 'Earth', 'Start', 'End', 'Location', 'best');
        legend(app.UIAxes, [app.trajHandle, app.LVLH_Axes.hx, app.SatelliteMarker], ...
                                     {'Trajectory', 'LVLH frame', 'Satellite'}, ...
                                         'Location', 'best', 'TextColor', 'w');

        % Set the camera viewing angle.
        view(app.UIAxes, 45, 25);
        
        % Release the hold on the axes.
        hold(app.UIAxes, 'off');
    end
    
    function updateInterface(app, history)
    %updateInterface Processes raw simulation data and calls plotting functions.
    % This function acts as a bridge between the simulation output and the GUI.
    % It extracts data from the history matrix, stores it in app properties,
    % and then triggers the appropriate plotting functions.
    %
    %   INPUTS:
    %       app     - The main application object.
    %       history - The raw (Nx19) simulation history matrix from the propagator.

        % --- Extract and Store Data ---
        app.time_vector   = history(:, 1) / 3600;    % Col 1: Time since epoch, converted to hours.
        app.GCRFpos       = history(:, 2:4);         % Cols 2-4: Inertial position vector [m] in GCRF.
        app.GCRFvel       = history(:, 5:7);         % Cols 5-7: Inertial velocity vector [m/s] in GCRF.
        app.accel_gravity = history(:, 8:10);        % Cols 8-10: Earth gravity acceleration [m/s^2].
        app.accel_drag    = history(:, 11:13);       % Cols 11-13: Atmospheric drag acceleration [m/s^2].
        app.accel_sun     = history(:, 14:16);       % Cols 14-16: Sun third-body gravity acceleration [m/s^2].
        app.accel_moon    = history(:, 17:19);       % Cols 17-19: Moon third-body gravity acceleration [m/s^2].
        app.accel_srp     = history(:, 20:22);       % Cols 20-22: Solar Radiation Pressure (SRP) acceleration [m/s^2].
        app.mag_field     = history(:, 23:25);       % Cols 23-25: Geomagnetic field vector in GCRF [Tesla].
        app.quatern       = history(:, 26:29);       % Cols 26-29: Reference quaternion GCRF to LVLH [q0, q1, q2, q3].

    end
    
    function plotData(app, data, title_str, xlabel_str, ylabel_str)
    %plotData Creates a 2D plot on the app's secondary UI axes.
    % A generic function to plot multi-column data against the time vector.
    %
    %   INPUTS:
    %       app        - The main application object.
    %       data       - (NxM double) The data to plot (e.g., position or acceleration).
    %       title_str  - (string) The title for the plot.
    %       xlabel_str - (string) The label for the x-axis.
    %       ylabel_str - (string) The label for the y-axis.
    
        colors = {'#0072BD', '#D95319', '#EDB120', '#7E2F8E', '#77AC30'}; % Blue, Orange, Yellow, Purple, Green

        % Link x-axes
        linkaxes([app.UIAxes_x, app.UIAxes_y, app.UIAxes_z], 'x');

        % Clear the secondary axes before plotting.
        cla(app.UIAxes_x, 'reset');
        cla(app.UIAxes_y, 'reset');
        cla(app.UIAxes_z, 'reset');

        % Plot the data against the stored time vector.
        plot(app.UIAxes_x, app.time_vector, data(:,1), 'Color', colors{1},'LineWidth', 1.5);
        plot(app.UIAxes_y, app.time_vector, data(:,2), 'Color', colors{2},'LineWidth', 1.5);
        plot(app.UIAxes_z, app.time_vector, data(:,3), 'Color', colors{3},'LineWidth', 1.5);
        
        % Configure labels and title.
        title(app.UIAxes_x, title_str);
        xlabel(app.UIAxes_x, xlabel_str{1}); ylabel(app.UIAxes_x, ylabel_str{1});
        xlabel(app.UIAxes_y, xlabel_str{2}); ylabel(app.UIAxes_y, ylabel_str{2});
        xlabel(app.UIAxes_z, xlabel_str{3}); ylabel(app.UIAxes_z, ylabel_str{3});
        grid(app.UIAxes_x, 'on');
        grid(app.UIAxes_y, 'on');
        grid(app.UIAxes_z, 'on');
    end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, simulationParameters, orbitalSimulation)
            %import org.orekit.utils.Constants;
            app.callMainApp = mainApp;
            app.simulationParameters = simulationParameters;
            updateInterface(app, orbitalSimulation);
            app.set_slider_limits();
            
            % --- Call Plotting Functions ---
            % LVLH scale definition in Km
            app.LVLH_Axes.scale_lvlh = app.simulationParameters.initialOrbit.a/2000; 

            % Plot the 3D orbit on the main axes.
            cla(app.UIAxes, 'reset');
            %axis(app.UIAxes, 'equal');
    
            plot3Dorbit(app, app.GCRFpos);
            
            % Plot the position data components over time on the secondary axes by default.
            plotData(app, app.GCRFpos / 1000, 'ICRF Position', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Position X (km)', 'Position Y (km)', 'Position Z (km)'});
        end

        % Clicked callback: OptionsDropDown
        function OptionsDropDownClicked(app, event)

        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;
            
            if strcmp(value, 'GCRF Position')
                plotData(app, app.GCRFpos / 1000, 'GCRF Position', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Position X (km)', 'Position Y (km)', 'Position Z (km)'});
            elseif strcmp(value, 'GCRF Velocity')
                plotData(app, app.GCRFvel, 'GCRF Velocity', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Vx (m/s)', 'Vy (m/s)', 'Vz (m/s)'});
            elseif strcmp(value, 'Earth Gravity Acceleration')
                plotData(app, app.accel_gravity, 'Earth Gravity Acceleration', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Atmospheric Drag Acceleration')
                plotData(app, app.accel_drag, 'Atmospheric Drag Acceleration', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Third-Body Acceleration (Sun)')
                plotData(app, app.accel_sun, 'Third-Body Acceleration (Sun)', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Third-Body Acceleration (Moon)')
                plotData(app, app.accel_moon, 'Third-Body Acceleration (Moon)', {'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Solar Radiation Pressure Acceleration')
                plotData(app, app.accel_srp, 'Solar Radiation Pressure Acceleration',{'Time (h)', 'Time (h)', 'Time (h)'}, {'Acceleration X (m/s^2)', 'Acceleration Y (m/s^2)', 'Acceleration Z (m/s^2)'});
            elseif strcmp(value, 'Magnetic field (GCRF frame)')
                plotData(app, app.mag_field*1E9, 'Magnetic field (GCRF frame)',{'Time (h)', 'Time (h)', 'Time (h)'}, {'Magnetic field X (nT)', 'Magnetic field Y (nT)', 'Magnetic field Z (nT)'});
            end
        end

        % Value changed function: TimehSlider
        function TimehSliderValueChanged(app, event)
          % TIMEHSLIDERVALUECHANGED Synchronizes the 3D visualization with the selected time.
            %
            % This callback executes as the user moves the time slider. It performs 
            % a lookup of the closest simulation state (position and orientation) 
            % and updates the graphical handles in the UIAxes accordingly.
            
            import adcsim.utils.*
            
            % -- Retrieve the current time value from the slider [hours]
            currentTime = app.TimehSlider.Value;
            
            % -- Search for the nearest neighbor index in the simulation time vector
            % We use min(abs(...)) to find the index that best matches the slider position.
            [~, idx] = min(abs(app.time_vector - currentTime));
            
            % -- Extract inertial position and reference quaternion for the selected index
            % Position is converted from meters [m] to kilometers [km].
            r = app.GCRFpos(idx, :) / 1000;
            q_current = app.quatern(idx, :); % Expected format: [q0, q1, q2, q3]
            
            % -- Update the Satellite Marker position
            % Modifying properties of an existing handle is faster than redrawing.
            set(app.SatelliteMarker, 'XData', r(1), 'YData', r(2), 'ZData', r(3));
            
            % -- Update the LVLH Reference Frame (Vectors and Labels)
            % Convert quaternion to a 3x3 Direction Cosine Matrix (DCM).
            B = quat2rot(q_current);
            
            % Call the generalized updateFrame utility to rotate and translate the triad.
            updateFrame(app.callMainApp, r', B, app.LVLH_Axes.scale_lvlh, ...
                        app.LVLH_Axes.hx, app.LVLH_Axes.lx, ...
                        app.LVLH_Axes.hy, app.LVLH_Axes.ly, ...
                        app.LVLH_Axes.hz, app.LVLH_Axes.lz);
            
            % 6. Refresh the UI display
            % 'limitrate' ensures a smooth animation by skipping frames if the 
            % renderer cannot keep up with the slider movement.
            drawnow limitrate;
        end

        % Value changed function: PlotMagneticFieldCheckBox
        function PlotMagneticFieldCheckBoxValueChanged(app, event)
            value = app.PlotMagneticFieldCheckBox.Value;
            if value 
               plotMagneticField(app, 1000);
            else
                hVectors = findobj(app.UIAxes, 'Tag', 'BFieldVectors');
            
                if ~isempty(hVectors)
                    delete(hVectors);
                end
            end
            app.updateOrbitLegend(); % Update the legend dynamically
        end

        % Value changing function: TimehSlider
        function TimehSliderValueChanging(app, event)
            currentTime = event.Value;
            % TIMEHSLIDERVALUECHANGED Synchronizes the 3D visualization with the selected time.
            %
            % This callback executes as the user moves the time slider. It performs 
            % a lookup of the closest simulation state (position and orientation) 
            % and updates the graphical handles in the UIAxes accordingly.
            
            import adcsim.utils.*
            
            % -- Retrieve the current time value from the slider [hours]
            %currentTime = app.TimehSlider.Value;
            
            % -- Search for the nearest neighbor index in the simulation time vector
            % We use min(abs(...)) to find the index that best matches the slider position.
            [~, idx] = min(abs(app.time_vector - currentTime));
            
            % -- Extract inertial position and reference quaternion for the selected index
            % Position is converted from meters [m] to kilometers [km].
            r = app.GCRFpos(idx, :) / 1000;
            q_current = app.quatern(idx, :); % Expected format: [q0, q1, q2, q3]
            
            % -- Update the Satellite Marker position
            % Modifying properties of an existing handle is faster than redrawing.
            set(app.SatelliteMarker, 'XData', r(1), 'YData', r(2), 'ZData', r(3));
            
            % -- Update the LVLH Reference Frame (Vectors and Labels)
            % Convert quaternion to a 3x3 Direction Cosine Matrix (DCM).
            B = quat2rot(q_current);
            
            % Call the generalized updateFrame utility to rotate and translate the triad.
            updateFrame(app.callMainApp, r', B, app.LVLH_Axes.scale_lvlh, ...
                        app.LVLH_Axes.hx, app.LVLH_Axes.lx, ...
                        app.LVLH_Axes.hy, app.LVLH_Axes.ly, ...
                        app.LVLH_Axes.hz, app.LVLH_Axes.lz);
            
            % 6. Refresh the UI display
            % 'limitrate' ensures a smooth animation by skipping frames if the 
            % renderer cannot keep up with the slider movement.
            drawnow limitrate;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_z = uiaxes(app.GridLayout);
            title(app.UIAxes_z, 'Title')
            xlabel(app.UIAxes_z, 'X')
            ylabel(app.UIAxes_z, 'Y')
            zlabel(app.UIAxes_z, 'Z')
            app.UIAxes_y = uiaxes(app.GridLayout);
            title(app.UIAxes_y, 'Title')
            xlabel(app.UIAxes_y, 'X')
            ylabel(app.UIAxes_y, 'Y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_x = uiaxes(app.GridLayout);
            title(app.UIAxes_x, 'Title')
            xlabel(app.UIAxes_x, 'X')
            ylabel(app.UIAxes_x, 'Y')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OptionsDropDownLabel = uilabel(app.GridLayout);
            app.OptionsDropDownLabel.Text = 'Options:';
            app.OptionsDropDown = uidropdown(app.GridLayout);
            app.OptionsDropDown.Items = {'GCRF Position', 'GCRF Velocity', 'Earth Gravity Acceleration', 'Atmospheric Drag Acceleration', 'Third-Body Acceleration (Sun)', 'Third-Body Acceleration (Moon)', 'Solar Radiation Pressure Acceleration', 'Magnetic field (GCRF frame)'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.ClickedFcn = createCallbackFcn(app, @OptionsDropDownClicked, true);
            app.OptionsDropDown.Value = 'GCRF Position';
            app.OrbitpropagationresultsLabel = uilabel(app.GridLayout);
            app.OrbitpropagationresultsLabel.Text = 'Orbit propagation results';
            app.TimehSliderLabel = uilabel(app.GridLayout);
            app.TimehSliderLabel.Text = 'Time (h):';
            app.TimehSlider = uislider(app.GridLayout);
            app.TimehSlider.ValueChangedFcn = createCallbackFcn(app, @TimehSliderValueChanged, true);
            app.TimehSlider.ValueChangingFcn = createCallbackFcn(app, @TimehSliderValueChanging, true);
            app.PlotMagneticFieldCheckBox = uicheckbox(app.GridLayout);
            app.PlotMagneticFieldCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotMagneticFieldCheckBoxValueChanged, true);
            app.PlotMagneticFieldCheckBox.Text = 'Plot Magnetic Field';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbit_propagation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end