classdef perturbationSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout4                 matlab.ui.container.GridLayout
        Slider                      matlab.ui.control.Slider
        DisplayDropDown             matlab.ui.control.DropDown
        DisplayDropDownLabel        matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        PerturbationSettingsExpressedinBodyFrameLabel  matlab.ui.control.Label
        EnableButtonsPanel          matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        MiscellaneousPerturbationSwitchLabel_2  matlab.ui.control.Label
        OrbitPerturbationSwitch     matlab.ui.control.Switch
        MiscellaneousPerturbationSwitch  matlab.ui.control.Switch
        MiscellaneousPerturbationSwitchLabel  matlab.ui.control.Label
        ConstantPerturbationSwitch  matlab.ui.control.Switch
        ConstantPerturbationSwitchLabel  matlab.ui.control.Label
        uipanel2                    matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        text7                       matlab.ui.control.Label
        text13                      matlab.ui.control.Label
        text12                      matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        w3_edit                     matlab.ui.control.NumericEditField
        w2_edit                     matlab.ui.control.NumericEditField
        w1_edit                     matlab.ui.control.NumericEditField
        A_edit                      matlab.ui.control.NumericEditField
        uipanel1                    matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        z_text                      matlab.ui.control.Label
        y_text                      matlab.ui.control.Label
        x_text                      matlab.ui.control.Label
        Tgz_edit                    matlab.ui.control.NumericEditField
        Tgy_edit                    matlab.ui.control.NumericEditField
        Tgx_edit                    matlab.ui.control.NumericEditField
        perturbation_text           matlab.ui.control.Label
        UIAxes_tggx                 matlab.ui.control.UIAxes
        UIAxes_tggy                 matlab.ui.control.UIAxes
        UIAxes_tggz                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        callMainApp             % window property
        dist_torque             % Disturbances torque
        h_line_init
        h_line_end
        h_patch
        mask                   
    end
    
    methods (Access = private)
        
        function update_Slider_Range(app, orekit_raw)
            % 1. Get total orbital duration from Orekit [s]
            t_min = min(orekit_raw.t); 
            t_max = max(orekit_raw.t); 

            % 2. Set the slider range and initial value
            app.Slider.Limits = [t_min, t_max];
            app.Slider.Value = t_min; % Default start
            
            % 3. Generate exactly 5 major ticks distributed along the time vector
            % using linspace to ensure uniform spacing.
            app.Slider.MajorTicks = linspace(t_min, t_max, 5);
        
        end

      function updateInterface(app, parameters, results)
            %%% 1. Synchronize Constant Disturbance Fields
            % Maps static torque components to the UI edit fields
            app.Tgx_edit.Value = parameters.disturbances.constant.Tdx;
            app.Tgy_edit.Value = parameters.disturbances.constant.Tdy;
            app.Tgz_edit.Value = parameters.disturbances.constant.Tdz;
        
            %%% 2. Synchronize Variable Disturbance Fields
            % Maps oscillation parameters for dynamic perturbations
            app.A_edit.Value  = parameters.disturbances.variable.A;
            app.w1_edit.Value = parameters.disturbances.variable.w1;
            app.w2_edit.Value = parameters.disturbances.variable.w2;
            app.w3_edit.Value = parameters.disturbances.variable.w3;
        
            %%% 3. Update Switch States (Logic Enables)
            % ValueIndex is used to toggle between ON (1) and OFF (0)
            app.ConstantPerturbationSwitch.ValueIndex      = parameters.disturbances.constant.enable;
            app.MiscellaneousPerturbationSwitch.ValueIndex = parameters.disturbances.variable.enable;
            app.OrbitPerturbationSwitch.ValueIndex         = parameters.disturbances.model_propagator.enable;
            
            %%% 4. Buffer Simulation Variables
            % Store current time and torque matrices in the app object for global access
            app.mask.t = parameters.disturbances.mask.t;
            app.mask.Ts = parameters.disturbances.mask.Ts;
            app.mask.nSteps = parameters.disturbances.mask.nSteps;
            app.mask.widhtWindow = parameters.disturbances.mask.widhtWindow;
            app.mask.t_ini = parameters.disturbances.mask.t_ini;
            app.mask.t_end = parameters.disturbances.mask.t_end;
            
            app.dist_torque.model_propagator = parameters.disturbances.model_propagator.enable; 
            app.dist_torque = mergeStructs(app.callMainApp, app.dist_torque, results.disturbances.torque);
            
            if app.dist_torque.model_propagator == 2
                app.DisplayDropDown.Enable = "On";
                app.Slider.Enable = "On";
                app.update_Slider_Range(results.disturbances.torque);
            else
                app.DisplayDropDown.Enable = "Off";
                app.Slider.Enable = "Off";
            end
            
            %%% 5. Graphical Update
            % Disable or enable buttons
            app.updateEnableValues(app.OrbitPerturbationSwitch.Value);

            % First run: Execute the external plotting function to initialize the UI Axes
            [app.h_line_init, app.h_line_end, app.h_patch] = app.my_plot_axis(...
                app.dist_torque.t, app.dist_torque.total, app.mask.t_ini, app.mask.t_end);
        end
        
        function newData = updateParameters(app)
            %%%Update constant disturbances
            newData.disturbances.constant.Tdx = app.Tgx_edit.Value;
            newData.disturbances.constant.Tdy = app.Tgy_edit.Value;
            newData.disturbances.constant.Tdz = app.Tgz_edit.Value;
            %%%Update miscellaneous disturbances
            newData.disturbances.variable.A  = app.A_edit.Value;
            newData.disturbances.variable.w1 = app.w1_edit.Value;
            newData.disturbances.variable.w2 = app.w2_edit.Value; 
            newData.disturbances.variable.w3 = app.w3_edit.Value; 
            %%%Read enable button
            newData.disturbances.constant.enable = app.ConstantPerturbationSwitch.ValueIndex;
            newData.disturbances.variable.enable = app.MiscellaneousPerturbationSwitch.ValueIndex;
            newData.disturbances.model_propagator.enable = app.OrbitPerturbationSwitch.ValueIndex;
            %%%Mask parameters
            newData.disturbances.mask.t = app.mask.t;
            newData.disturbances.mask.Ts = app.mask.Ts;
            newData.disturbances.mask.nSteps = app.mask.nSteps;
            newData.disturbances.mask.widhtWindow = app.mask.widhtWindow;
            newData.disturbances.mask.t_ini = app.mask.t_ini;
            newData.disturbances.mask.t_end = app.mask.t_end;
            %%%Gui identifier
            newData.gui_identifier = 2;
        end

        function [h_line_init, h_line_end, h_patch] = my_plot_axis(app, plot_time, data, ts_init, ts_end)
            % Define the target UIAxes handles for the 3-axis torque plotting
            axes_list = [app.UIAxes_tggx, app.UIAxes_tggy, app.UIAxes_tggz];
            labels = {'X', 'Y', 'Z'};
            
            % Pre-allocate arrays for graphics handles to optimize performance
            % If the conditions below are not met, these remain empty gobjects
            h_line_init = gobjects(3, 1);
            h_line_end  = gobjects(3, 1);
            h_patch     = gobjects(3, 1);
            
            for i = 1:3
                ax = axes_list(i);
                
                % 1. Plot the main environmental torque data
                plot(ax, plot_time, data(i, :),'.', 'LineWidth', 1);
                
                % Enable 'hold' to overlay indicators without overwriting the plot
                hold(ax, 'on');
                
                % --- Pre-calculate Limits ---
                % Apply 'tight' limits first to get the exact Y-boundaries 
                axis(ax, 'tight'); 
                y_lims = ax.YLim; 
                
                % 2. Sliding window indicators logic (Shaded Region)
                % Verify if the orbital propagator model is currently active
                if app.dist_torque.model_propagator == 2
                    % Check if both initial and final time arguments are valid
                    if nargin >= 5 && isfinite(ts_init) && isfinite(ts_end)
                        
                        % Define the coordinates for the shaded rectangle vertices
                        x_patch = [ts_init, ts_end, ts_end, ts_init];
                        y_patch = [y_lims(1), y_lims(1), y_lims(2), y_lims(2)];
                        
                        % Draw the semi-transparent shaded region and store handle
                        h_patch(i) = patch(ax, 'XData', x_patch, 'YData', y_patch, ...
                              'FaceColor', 'red', 'FaceAlpha', 0.15, 'EdgeColor', 'none');
                        
                        % Draw vertical dashed lines and store their handles
                        h_line_init(i) = xline(ax, ts_init, '--r', 'LineWidth', 1.5);
                        h_line_end(i)  = xline(ax, ts_end, '--r', 'LineWidth', 1.5);
                    end
                end
                
                % Disable 'hold' to keep axes behavior consistent
                hold(ax, 'off'); 
                
                % 3. Axes formatting and labels
                xlabel(ax, 'Time (s)'); 
                ylabel(ax, sprintf('Torque in %s (Nm)', labels{i}));
                title(ax, '');      
                grid(ax, 'on');     
                
                % Let the Y-axis remain auto; X-axis fits the data exactly
                ax.YLimMode = 'auto';
                %ylim(ax, [min(data(i, :)), max(data(i, :))]);            
                xlim(ax, [plot_time(1), plot_time(end)]);
            end
            
            % 4. Link the X-axes for synchronized zooming and panning
            linkaxes(axes_list, 'x');
        end
        
        function update_window_position(app, new_ts_init, new_ts_end)
            line_i = app.h_line_init;
            line_end = app.h_line_end;
            patch  = app.h_patch;
             % Loop through the 3 subplots
            for i = 1:3
                % Safety check: Only update if the graphics objects were actually created
                % (i.e., if the propagator model was 2 and inputs were valid)
                if isgraphics(patch(i)) && isgraphics(line_i(i))
                    
                    % 1. Move the vertical lines to the new timestamps
                    line_i(i).Value = new_ts_init;
                    line_end(i).Value  = new_ts_end;
                    
                    % 2. Update the X-coordinates of the shaded region's vertices
                    new_x_patch = [new_ts_init, new_ts_end, new_ts_end, new_ts_init];
                    patch(i).XData = new_x_patch;
                end
            end
        end

        function [t_dense, torque_dense] = get_localized_resampling(~, orekit_t, orekit_torque, t_ini, t_end, Ts)
            % GET_LOCALIZED_RESAMPLING extracts and interpolates orbital data for a 
            % specific time window. It is generalized to work perfectly whether the 
            % mask covers zero points (sub-step), one point, or multiple points.
            %
            % Inputs:
            %   orekit_t      - [1xN] Sparse time vector from Orekit
            %   orekit_torque - [3xN] Sparse disturbance torque matrix
            %   t_ini         - [double] Start time of the mask
            %   t_end         - [double] End time of the mask
            %   Ts            - [double] Target sampling time for adcSim (e.g., 1e-4)
            %
            % Outputs:
            %   t_dense       - High-resolution time vector strictly within the mask
            %   torque_dense  - Interpolated torque matrix at high frequency
        
            % 1. Find the strict mathematical boundaries
            % Find the index of the closest Orekit point BEFORE or exactly at t_ini
            idx_start = find(orekit_t <= t_ini, 1, 'last');
            
            % Find the index of the closest Orekit point AFTER or exactly at t_end
            idx_end   = find(orekit_t >= t_end, 1, 'first');
            
            % Handle out-of-bounds edge cases (if mask is beyond available data)
            if isempty(idx_start), idx_start = 1; end
            if isempty(idx_end),   idx_end = length(orekit_t); end
            
            % 2. Apply a Safety Margin for generalized cubic interpolation
            % We expand the indices by 2 points on each side. This guarantees that 
            % 'pchip' has enough boundary context to calculate slopes correctly, 
            % regardless of how many points are inside the actual mask.
            margin_points = 2;
            idx_start = max(1, idx_start - margin_points);
            idx_end   = min(length(orekit_t), idx_end + margin_points);
            
            % 3. Extract the localized dataset
            % If the mask covers 50 points, this slice will grab all 50 + margins.
            % If the mask covers 0 points, this slice will grab just the margins.
            local_t = orekit_t(idx_start:idx_end);
            local_torque = orekit_torque(:, idx_start:idx_end);
            
            % 4. Generate the target high-density time vector for adcSim
            % Using round ensures we don't drop the last sample due to floating point precision
            num_steps = round((t_end - t_ini) / Ts) + 1;
            t_dense = linspace(t_ini, t_end, num_steps);
            
            % 5. Perform the generalized interpolation
            torque_dense = zeros(3, num_steps);
            for i = 1:3
                % 'pchip' ensures shape preservation without artificial oscillations
                torque_dense(i, :) = interp1(local_t, local_torque(i, :), t_dense, 'pchip');
            end
        end
        
        function updateEnableValues(app, value)            
            if strcmp(value, 'On')
                % --- ORBITAL MODE ACTIVE ---
                
                % 1. Lock out manual perturbation switches to prevent conflicts
                app.MiscellaneousPerturbationSwitch.Enable = 'Off';
                app.ConstantPerturbationSwitch.Enable      = 'Off';
                
                % 2. Disable manual input fields for Constant Perturbation
                app.Tgx_edit.Enable = 'Off';
                app.Tgy_edit.Enable = 'Off';
                app.Tgz_edit.Enable = 'Off';
                
                % 3. Disable manual input fields for Variable Perturbation
                app.A_edit.Enable  = 'Off';
                app.w1_edit.Enable = 'Off';
                app.w2_edit.Enable = 'Off';
                app.w3_edit.Enable = 'Off';
                
                % 4. Enable UI controls specific to the Orbit Propagator mode
                app.DisplayDropDown.Enable = 'On';
                app.Slider.Enable          = 'On';
                
                % 5. Programmatically trigger the Apply button to update backend logic
                % Sending an empty array [] since the 'event' argument is not used
                app.ApplyButtonPushed([]); 
                
                % 6. Recalculate and reset the slider's operational range and position
                app.update_Slider_Range(app.dist_torque);
                app.Slider.Value = app.mask.t_ini; % Reset slider to start time
                
            else
                % --- MANUAL MODES ACTIVE ---
                
                % 1. Unlock manual perturbation switches
                app.MiscellaneousPerturbationSwitch.Enable = 'On';
                app.ConstantPerturbationSwitch.Enable      = 'On';
                
                % 2. Re-enable manual input fields for Constant Perturbation
                app.Tgx_edit.Enable = 'On';
                app.Tgy_edit.Enable = 'On';
                app.Tgz_edit.Enable = 'On';
                
                % 3. Re-enable manual input fields for Variable Perturbation
                app.A_edit.Enable  = 'On';
                app.w1_edit.Enable = 'On';
                app.w2_edit.Enable = 'On';
                app.w3_edit.Enable = 'On';
                
                % 4. Disable UI controls specific to the Orbit Propagator mode
                app.DisplayDropDown.Enable = 'Off';
                app.Slider.Enable          = 'Off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, parameters, results)
            app.callMainApp = mainApp;
            updateInterface(app, parameters, results);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            data = app.updateParameters();
            updated_torques = getDisturbances(app.callMainApp, data.disturbances, app.mask);
            app.dist_torque = mergeStructs(app.callMainApp, app.dist_torque, updated_torques );
            plot_time = app.dist_torque.t;
            app.DisplayDropDown.Value = 'Total torque';
            [app.h_line_init, app.h_line_end, app.h_patch] = app.my_plot_axis(plot_time, app.dist_torque.total, app.mask.t_ini, app.mask.t_end);
            if app.dist_torque.model_propagator == 2
               [t, torque]= app.get_localized_resampling(app.dist_torque.t, app.dist_torque.total, app.mask.t_ini, app.mask.t_end, app.mask.Ts);
            end
           
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dataToSend = app.updateParameters();
            importData(app.callMainApp, dataToSend);
            delete(app);
        end

        % Value changed function: OrbitPerturbationSwitch
        function OrbitPerturbationSwitchValueChanged(app, event)
            % Get the current state of the switch ('On' or 'Off')
            value = app.OrbitPerturbationSwitch.Value;
            app.updateEnableValues(value);
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(app, event)
            value = app.DisplayDropDown.Value;
            switch value
                case 'Total torque'
                    data_to_plot = app.dist_torque.total;
                case 'Gravity Gradient torque'
                    data_to_plot = app.dist_torque.gravity_torque;
                case 'Atmospheric Drag torque'
                    data_to_plot = app.dist_torque.drag_torque;
                case 'Solar radiation pressure torque'
                    data_to_plot = app.dist_torque.solar_torque;
                otherwise
                    data_to_plot = app.dist_torque.total;
            end
            
            % Update new selection
             [app.h_line_init, app.h_line_end, app.h_patch]  = app.my_plot_axis(app.dist_torque.t, data_to_plot, app.mask.t_ini, app.mask.t_end);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
           app.mask.t_ini = event.Value;
           app.mask.t_end = app.mask.t_ini + app.mask.widhtWindow;
           app.update_window_position(app.mask.t_ini, app.mask.t_end)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.UIAxes_tggz = uiaxes(app.GridLayout4);
            title(app.UIAxes_tggz, 'Title')
            xlabel(app.UIAxes_tggz, 'X')
            ylabel(app.UIAxes_tggz, 'Y')
            zlabel(app.UIAxes_tggz, 'Z')
            app.UIAxes_tggy = uiaxes(app.GridLayout4);
            title(app.UIAxes_tggy, 'Title')
            xlabel(app.UIAxes_tggy, 'X')
            ylabel(app.UIAxes_tggy, 'Y')
            zlabel(app.UIAxes_tggy, 'Z')
            app.UIAxes_tggx = uiaxes(app.GridLayout4);
            title(app.UIAxes_tggx, 'Title')
            xlabel(app.UIAxes_tggx, 'X')
            ylabel(app.UIAxes_tggx, 'Y')
            zlabel(app.UIAxes_tggx, 'Z')
            app.uipanel1 = uipanel(app.GridLayout4);
            app.uipanel1.Title = 'Constant Perturbation';
            app.uipanel1.Tag = 'uipanel1';
            app.GridLayout = uigridlayout(app.uipanel1);
            app.perturbation_text = uilabel(app.GridLayout);
            app.perturbation_text.Tag = 'text6';
            app.perturbation_text.Text = 'Perturbation (Nm):';
            app.Tgx_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgx_edit.ValueDisplayFormat = '%.2E';
            app.Tgy_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgy_edit.ValueDisplayFormat = '%.2E';
            app.Tgz_edit = uieditfield(app.GridLayout, 'numeric');
            app.Tgz_edit.ValueDisplayFormat = '%.2E';
            app.x_text = uilabel(app.GridLayout);
            app.x_text.Tag = 'text6';
            app.x_text.Text = 'X';
            app.y_text = uilabel(app.GridLayout);
            app.y_text.Tag = 'text6';
            app.y_text.Text = 'Y';
            app.z_text = uilabel(app.GridLayout);
            app.z_text.Tag = 'text6';
            app.z_text.Text = 'Z';
            app.uipanel2 = uipanel(app.GridLayout4);
            app.uipanel2.Title = 'Miscellaneous Perturbation';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout2 = uigridlayout(app.uipanel2);
            app.A_edit = uieditfield(app.GridLayout2, 'numeric');
            app.A_edit.ValueDisplayFormat = '%.E';
            app.w1_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w1_edit.ValueDisplayFormat = '%.E';
            app.w2_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w2_edit.ValueDisplayFormat = '%.E';
            app.w3_edit = uieditfield(app.GridLayout2, 'numeric');
            app.w3_edit.ValueDisplayFormat = '%.E';
            app.text8 = uilabel(app.GridLayout2);
            app.text8.Tag = 'text8';
            app.text8.Text = '[ sin(';
            app.text10 = uilabel(app.GridLayout2);
            app.text10.Tag = 'text10';
            app.text10.Text = 't), sin(';
            app.text12 = uilabel(app.GridLayout2);
            app.text12.Tag = 'text12';
            app.text12.Text = 't), sin(';
            app.text13 = uilabel(app.GridLayout2);
            app.text13.Tag = 'text13';
            app.text13.Text = 't)] ''';
            app.text7 = uilabel(app.GridLayout2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Perturbation (Nm):';
            app.EnableButtonsPanel = uipanel(app.GridLayout4);
            app.EnableButtonsPanel.Title = 'Enable Buttons';
            app.GridLayout3 = uigridlayout(app.EnableButtonsPanel);
            app.ConstantPerturbationSwitchLabel = uilabel(app.GridLayout3);
            app.ConstantPerturbationSwitchLabel.Text = 'Constant Perturbation';
            app.ConstantPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.MiscellaneousPerturbationSwitchLabel = uilabel(app.GridLayout3);
            app.MiscellaneousPerturbationSwitchLabel.Text = 'Miscellaneous Perturbation';
            app.MiscellaneousPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.OrbitPerturbationSwitch = uiswitch(app.GridLayout3, 'slider');
            app.OrbitPerturbationSwitch.ValueChangedFcn = createCallbackFcn(app, @OrbitPerturbationSwitchValueChanged, true);
            app.MiscellaneousPerturbationSwitchLabel_2 = uilabel(app.GridLayout3);
            app.MiscellaneousPerturbationSwitchLabel_2.Text = 'Orbital Perturbations';
            app.PerturbationSettingsExpressedinBodyFrameLabel = uilabel(app.GridLayout4);
            app.PerturbationSettingsExpressedinBodyFrameLabel.Text = 'Perturbation Settings (Expressed in Body Frame)';
            app.ApplyButton = uibutton(app.GridLayout4, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SaveButton = uibutton(app.GridLayout4, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DisplayDropDownLabel = uilabel(app.GridLayout4);
            app.DisplayDropDownLabel.Text = 'Display:';
            app.DisplayDropDown = uidropdown(app.GridLayout4);
            app.DisplayDropDown.Items = {'Total torque', 'Gravity Gradient torque', 'Atmospheric Drag torque', 'Solar radiation pressure torque'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayDropDownValueChanged, true);
            app.DisplayDropDown.Value = 'Total torque';
            app.Slider = uislider(app.GridLayout4);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perturbationSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end