classdef reactionWheels_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        SaveButton                    matlab.ui.control.Button
        PIDGainsPanel                 matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        KIEditField                   matlab.ui.control.NumericEditField
        KIEditFieldLabel              matlab.ui.control.Label
        NuEditField                   matlab.ui.control.NumericEditField
        NuEditFieldLabel              matlab.ui.control.Label
        KDEditField                   matlab.ui.control.NumericEditField
        KDEditFieldLabel              matlab.ui.control.Label
        KPEditField                   matlab.ui.control.NumericEditField
        KPEditFieldLabel              matlab.ui.control.Label
        MotormodelparametersPanel     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        keBackEMFconstantVsradEditField  matlab.ui.control.NumericEditField
        keBackEMFconstantVsradEditFieldLabel  matlab.ui.control.Label
        RResistancesEditField         matlab.ui.control.NumericEditField
        RResistancesLabel             matlab.ui.control.Label
        LInductanceHEditField         matlab.ui.control.NumericEditField
        LInductanceHEditFieldLabel    matlab.ui.control.Label
        cCoulombfrictioncoefficientNmEditField  matlab.ui.control.NumericEditField
        cCoulombfrictioncoefficientNmEditFieldLabel  matlab.ui.control.Label
        BViscousfrictioncoefficientNmsEditField  matlab.ui.control.NumericEditField
        BViscousfrictioncoefficientNmsEditFieldLabel  matlab.ui.control.Label
        JrwRotorinertiakgm2EditField  matlab.ui.control.NumericEditField
        JrwRotorinertiakgm2EditFieldLabel  matlab.ui.control.Label
        KtTorqueconstantNmAEditField  matlab.ui.control.NumericEditField
        KtTorqueconstantNmAEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        callMainApp % Description
    end
    
    methods (Access = private)
        
        function updateInterface(app, data)
            app.KtTorqueconstantNmAEditField.Value = data.rw.motor.kt;
            app.JrwRotorinertiakgm2EditField.Value = data.rw.motor.Jrw;
            app.BViscousfrictioncoefficientNmsEditField.Value = data.rw.motor.b;
            app.cCoulombfrictioncoefficientNmEditField.Value  = data.rw.motor.c;
            app.LInductanceHEditField.Value = data.rw.motor.L;
            app.RResistancesEditField.Value = data.rw.motor.R;
            app.keBackEMFconstantVsradEditField.Value = data.rw.motor.ke;

            app.KPEditField.Value = data.rw.motor.pid.kp;
            app.KIEditField.Value = data.rw.motor.pid.ki;
            app.KDEditField.Value = data.rw.motor.pid.kd;
            app.NuEditField.Value = data.rw.motor.pid.Nu;
        end

        function data = updateParameters(app)
            data.rw.motor.kt = app.KtTorqueconstantNmAEditField.Value;
            data.rw.motor.Jrw = app.JrwRotorinertiakgm2EditField.Value;
            data.rw.motor.b = app.BViscousfrictioncoefficientNmsEditField.Value;
            data.rw.motor.c = app.cCoulombfrictioncoefficientNmEditField.Value;
            data.rw.motor.L = app.LInductanceHEditField.Value;
            data.rw.motor.R = app.RResistancesEditField.Value;
            data.rw.motor.ke = app.keBackEMFconstantVsradEditField.Value;

            data.rw.motor.pid.kp = app.KPEditField.Value;
            data.rw.motor.pid.ki = app.KIEditField.Value;
            data.rw.motor.pid.kd = app.KDEditField.Value;
            data.rw.motor.pid.Nu = app.NuEditField.Value;
            
            data.gui_identifier = 9;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, mainAppData)
            app.callMainApp = mainApp;
            updateInterface(app, mainAppData);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            dataToSend = updateParameters(app);
            importData(app.callMainApp, dataToSend);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MotormodelparametersPanel = uipanel(app.GridLayout);
            app.MotormodelparametersPanel.Title = 'Motor model parameters';
            app.GridLayout2 = uigridlayout(app.MotormodelparametersPanel);
            app.KtTorqueconstantNmAEditFieldLabel = uilabel(app.GridLayout2);
            app.KtTorqueconstantNmAEditFieldLabel.Text = '(Kt) Torque constant [N*m/A] :';
            app.KtTorqueconstantNmAEditField = uieditfield(app.GridLayout2, 'numeric');
            app.JrwRotorinertiakgm2EditFieldLabel = uilabel(app.GridLayout2);
            app.JrwRotorinertiakgm2EditFieldLabel.Text = '(Jrw) Rotor inertia [kg*m^2]';
            app.JrwRotorinertiakgm2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.BViscousfrictioncoefficientNmsEditFieldLabel = uilabel(app.GridLayout2);
            app.BViscousfrictioncoefficientNmsEditFieldLabel.Text = '(B) Viscous friction coefficient [N*m*s]';
            app.BViscousfrictioncoefficientNmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.cCoulombfrictioncoefficientNmEditFieldLabel = uilabel(app.GridLayout2);
            app.cCoulombfrictioncoefficientNmEditFieldLabel.Text = '(c) Coulomb friction coefficient [N*m]';
            app.cCoulombfrictioncoefficientNmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LInductanceHEditFieldLabel = uilabel(app.GridLayout2);
            app.LInductanceHEditFieldLabel.Text = '(L) Inductance [H]';
            app.LInductanceHEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RResistancesLabel = uilabel(app.GridLayout2);
            app.RResistancesLabel.Text = '(R) Resistances [Ω]';
            app.RResistancesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.keBackEMFconstantVsradEditFieldLabel = uilabel(app.GridLayout2);
            app.keBackEMFconstantVsradEditFieldLabel.Text = '(ke) Back-EMF constant [V*s/rad]';
            app.keBackEMFconstantVsradEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PIDGainsPanel = uipanel(app.GridLayout);
            app.PIDGainsPanel.Title = 'PID Gains';
            app.GridLayout3 = uigridlayout(app.PIDGainsPanel);
            app.KPEditFieldLabel = uilabel(app.GridLayout3);
            app.KPEditFieldLabel.Text = 'KP';
            app.KPEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KDEditFieldLabel = uilabel(app.GridLayout3);
            app.KDEditFieldLabel.Text = 'KD';
            app.KDEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NuEditFieldLabel = uilabel(app.GridLayout3);
            app.NuEditFieldLabel.Text = 'Nu';
            app.NuEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KIEditFieldLabel = uilabel(app.GridLayout3);
            app.KIEditFieldLabel.Text = 'KI';
            app.KIEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reactionWheels_parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end